classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavePortfolioasMenu             matlab.ui.container.Menu
        LoadPortfolioMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        PortfolioSelectionTab           matlab.ui.container.Tab
        SHORTTRANSACTIONSLabel          matlab.ui.control.Label
        LONGTRANSACTIONSLabel           matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        PortfolioCompositionPanel       matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        AddOptiontoPortfolioPanel       matlab.ui.container.Panel
        UndoButton                      matlab.ui.control.Button
        RelevantInformationsTextArea    matlab.ui.control.TextArea
        RelevantInformationsTextAreaLabel  matlab.ui.control.Label
        UpdatePortfolioButton           matlab.ui.control.Button
        UnderlyingDropDown              matlab.ui.control.DropDown
        UnderlyingDropDownLabel         matlab.ui.control.Label
        QuantitySpinnerLabel            matlab.ui.control.Label
        QuantitySpinner                 matlab.ui.control.Spinner
        SimulationTrialsEditField       matlab.ui.control.NumericEditField
        SimulationTrialsEditFieldLabel  matlab.ui.control.Label
        BinaryPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BinaryValueEditFieldLabel       matlab.ui.control.Label
        BinaryValueEditField            matlab.ui.control.NumericEditField
        BarrierPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BarrierValueEditField           matlab.ui.control.NumericEditField
        BarrierValueLabel               matlab.ui.control.Label
        BarrierTypeLabel                matlab.ui.control.Label
        BarrierTypeDropDown             matlab.ui.control.DropDown
        YieldLabel                      matlab.ui.control.Label
        YieldEditField                  matlab.ui.control.NumericEditField
        OptionMaturityDatePicker        matlab.ui.control.DatePicker
        OptionMaturityDatePickerLabel   matlab.ui.control.Label
        VolatilityEditField             matlab.ui.control.NumericEditField
        VolatilityEditFieldLabel        matlab.ui.control.Label
        UnderlyingPriceEditField        matlab.ui.control.NumericEditField
        UnderlyingPriceEditFieldLabel   matlab.ui.control.Label
        StrikeEditFieldLabel            matlab.ui.control.Label
        StrikeEditField                 matlab.ui.control.NumericEditField
        InstrumentTypeDropDownLabel     matlab.ui.control.Label
        InstrumentTypeDropDown          matlab.ui.control.DropDown
        OptionTypeButtonGroup           matlab.ui.container.ButtonGroup
        PutButton                       matlab.ui.control.RadioButton
        CallButton                      matlab.ui.control.RadioButton
        PositionButtonGroup             matlab.ui.container.ButtonGroup
        ShortButton                     matlab.ui.control.RadioButton
        LongButton                      matlab.ui.control.RadioButton
        OptionStyleButtonGroup          matlab.ui.container.ButtonGroup
        AmericanButton                  matlab.ui.control.RadioButton
        EuropeanButton                  matlab.ui.control.RadioButton
        DeleteAllRegistrationsButton    matlab.ui.control.Button
        AddRegistrationButton           matlab.ui.control.Button
        SetDateandRatesButton           matlab.ui.control.Button
        DeskPerformanceTab              matlab.ui.container.Tab
        PortfolioPerformancePanel       matlab.ui.container.Panel
        UIAxes12                        matlab.ui.control.UIAxes
        CashFlowsPanel                  matlab.ui.container.Panel
        UIAxes11                        matlab.ui.control.UIAxes
        PortfolioSummaryandCashFlowsPanel  matlab.ui.container.Panel
        UpdateDataButton                matlab.ui.control.Button
        UnderlyingDropDown_2            matlab.ui.control.DropDown
        UnderlyingDropDown_2Label       matlab.ui.control.Label
        USDAUDPORTFOLIOLabel            matlab.ui.control.Label
        UITable12                       matlab.ui.control.Table
        UITable11                       matlab.ui.control.Table
        TimeSeriesAnalysisTab           matlab.ui.container.Tab
        CorrelationMapandSummaryStatisticsControlPanel  matlab.ui.container.Panel
        BackgroundColorButton           matlab.ui.control.StateButton
        EndDateDatePicker_2             matlab.ui.control.DatePicker
        EndDateDatePicker_2Label        matlab.ui.control.Label
        StartDateDatePicker_2           matlab.ui.control.DatePicker
        StartDateDatePicker_2Label      matlab.ui.control.Label
        CorrelationMapPanel             matlab.ui.container.Panel
        UITable5                        matlab.ui.control.Table
        LogReturnsDistributionFitterPanel  matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        DistributionTypeDropDown        matlab.ui.control.DropDown
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        OverlayDistributionCheckBox     matlab.ui.control.CheckBox
        StartDateDatePicker_3           matlab.ui.control.DatePicker
        StartDateDatePicker_3Label      matlab.ui.control.Label
        CurrencyDropDown                matlab.ui.control.DropDown
        CurrencyDropDownLabel           matlab.ui.control.Label
        UIAxes7                         matlab.ui.control.UIAxes
        TimeSeriesChartControlPanel     matlab.ui.container.Panel
        Series2DropDown                 matlab.ui.control.DropDown
        Series2DropDownLabel            matlab.ui.control.Label
        Series1DropDown                 matlab.ui.control.DropDown
        Series1DropDownLabel            matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        TimeSeriesChartPanel            matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        VaRCalculationandBacktestingTab  matlab.ui.container.Tab
        VaRLimitsCheckControlPanel_2    matlab.ui.container.Panel
        EndDateDatePicker_3             matlab.ui.control.DatePicker
        EndDateDatePicker_3Label        matlab.ui.control.Label
        StartDateDatePicker_4           matlab.ui.control.DatePicker
        StartDateDatePicker_4Label      matlab.ui.control.Label
        VaRBacktestingControlsPanel     matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        StartBacktestingButton          matlab.ui.control.Button
        SamplingRollingWindowDropDown   matlab.ui.control.DropDown
        SamplingRollingWindowDropDownLabel  matlab.ui.control.Label
        VaRBacktestingHorizonDatePicker  matlab.ui.control.DatePicker
        VaRBacktestingHorizonDatePickerLabel  matlab.ui.control.Label
        MainControlPanel                matlab.ui.container.Panel
        DeskVaRLimitEditField           matlab.ui.control.NumericEditField
        DeskVaRLimitEditFieldLabel      matlab.ui.control.Label
        GenerateStepladderButton        matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        RiskHorizonEditField            matlab.ui.control.NumericEditField
        RiskHorizonEditFieldLabel       matlab.ui.control.Label
        SamplingPeriodDropDown          matlab.ui.control.DropDown
        SamplingPeriodDropDownLabel     matlab.ui.control.Label
        StepladderCurrencyDropDown      matlab.ui.control.DropDown
        StepladderCurrencyDropDownLabel  matlab.ui.control.Label
        USDAUDPORTFOLIOSUMMARYLabel     matlab.ui.control.Label
        STEPLADDERANALYSISLabel         matlab.ui.control.Label
        CURRENTPORTFOLIOVARANDESLabel   matlab.ui.control.Label
        VARBACKTESTINGLabel             matlab.ui.control.Label
        UITable10                       matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        UITable7                        matlab.ui.control.Table
        UITable6                        matlab.ui.control.Table
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        VolatilitySurfaceandPortfolioGreksChartsTab  matlab.ui.container.Tab
        VolatilitySurfacePlotPanel      matlab.ui.container.Panel
        RateEditField                   matlab.ui.control.NumericEditField
        RateEditFieldLabel              matlab.ui.control.Label
        UnderlyingEditField             matlab.ui.control.NumericEditField
        UnderlyingEditFieldLabel        matlab.ui.control.Label
        GenerateChartButton             matlab.ui.control.Button
        ImportOptionsDataButton         matlab.ui.control.Button
        UIAxes10                        matlab.ui.control.UIAxes
        PortfolioGammaandDeltaPanel     matlab.ui.container.Panel
        PlotPortfolioDataButton         matlab.ui.control.Button
        SensitivityChartCurrencyDropDown  matlab.ui.control.DropDown
        SensitivityChartCurrencyDropDownLabel  matlab.ui.control.Label
        UIAxes13                        matlab.ui.control.UIAxes
    end

    
%     Dichiarazione Properties
    
    properties (Access = public)
        RateDialog % Rate and Settle Panel 
        NoRatesSetERROR % sconosciuto
        CurrencySetError % Error when setting option base currency with empty rates data
        MinLimit %Trasferimento a RateSettleDialog
    end
    
    properties (Access = public)
       
        Settle % Variabile temporanea per salvare la data dalla finestra dialogo
        ZeroCurveEUR
        ZeroCurveUSD
        ZeroCurveAUD
        ZeroCurveGBP
       
       
    end
    
    properties (Access = private)
        FredData
        Ret % DATA LOG RETURNS, da usare nell'heatmap
        InstSens 
        Prices
        RetTable
        RetMatrix
        PriceMatrix % Matrice unica per i tassi di cambio
        PriceTable % Tabella unica per i tassi di cambio
        Q  % Q strumenti in AddtoPortfolio Callback
        Instr % Strumenti
        counter = 0 % contatore push AddtoPortfolio
        counter2 = 0 %Conta il numero di eliminazioni di strumenti uguali
        Pricer %Pricer
        OptionTypeCategorical
        OptionTypeCategoricalLong
        OptionTypeCategoricalShort % Crea un array di strumenti
        ShortInstrumentCounter = 0
        LongInstrumentCounter = 0
        TestInstr 
        Dates 
        Rates
        Currency
        SummaryTable
        StoreDataButtonCounter = 0 
        
        file %VOLATILITY SURFACE DATA IMPORT
        PortfolioData 
        TransactionData
        Costs
        Revenues 
        CurrencyRev
        DatesRev
        CurrencyCost
        DatesCost
        
        LongCategories_Quantities
        ShortCategories_Quantities
        
        PortfolioBackup %UNDO BUTTON 
        PortSens
        InstPrice
    end


% DICHIARAZIONE FUNZIONI
    
    methods (Access = public)
        function TestRatesError(app,value)
             TEST = RateCurveTEST(app.ZeroCurveEUR,app.ZeroCurveUSD,app.ZeroCurveAUD,app.ZeroCurveGBP);


% %% INSERIRE QARNING IN TEXT AREA           
            if TEST(1) == 0 & value == 'EUR'
                app.RelevantInformationsTextArea.Value = "There are no EUR rates data. Select another underlying or press ''Set Date and Rates Button' to set EUR rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(1) == 1 & value == 'EUR'
                app.RelevantInformationsTextArea.Value = "EUR rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            if TEST(2) == 0 & value == 'USD'
               app.RelevantInformationsTextArea.Value = "There are no USD rates data. Select another underlying or press ''Set Date and Rates Button' to set USD rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(2) == 1 & value == 'USD'
                app.RelevantInformationsTextArea.Value = "USD rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            
            if TEST(3) == 0 & value == 'AUD'
               app.RelevantInformationsTextArea.Value = "There are no AUD rates data. Select another underlying or press ''Set Date and Rates Button' to set AUD rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(3) == 1 & value == 'AUD'
                app.RelevantInformationsTextArea.Value = "AUD rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            
            if TEST(4) == 0 & value == 'GBP'
               app.RelevantInformationsTextArea.Value = "There are no GBP rates data. Select another underlying or press ''Set Date and Rates Button' to set GBP rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(4) == 1 & value == 'GBP'
                app.RelevantInformationsTextArea.Value = "GBP rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end


        end
    end


    methods (Access = private)
        
        
% PORTFOLIO PRICING DA ASSOCIARE AL PRICING COMPLETO        
        
        function [PortPrice, InstPrice, PortSens, InstSens] = PortCreationPricing(app,Instruments,Q,Pricer)


            myport = finportfolio(Instruments,Pricer,Q);

            [PortPrice, InstPrice, PortSens, InstSens] = pricePortfolio(myport);

            
        end

% FUNZIONE PER SETTARE A 0 I GRAFICI E LE TABELLE DELLA HOME
        
        function ReSetHomePlots(app)
            app.UITable2.Data = [];
            app.UITable3.Data = [];
            
            p = pie(app.UIAxes2,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes3,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes4,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes5,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            colormap(app.UIAxes2,parula(0));
            colormap(app.UIAxes3,parula(0));
            colormap(app.UIAxes4,parula(0));
            colormap(app.UIAxes5,parula(0));
            
        end
        
        
        function UpdateHomePlots(app,InstPrice,InstSens,PortSens)
            
%     Creazione Font
            fontstyle = uistyle;
            fontstyle.FontWeight = 'bold';
            fontstyleAl = uistyle;
            fontstyleAl.HorizontalAlignment = 'center';
            
%     Dati pos long     

            app.UITable2.Data = InstSens(InstSens.Price > 0,:);
            HeightTable = height(app.UITable2.Data);
            c2 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c2(i,:));
                s.FontColor = c2(i,:);              
                addStyle(app.UITable2,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable2,fontstyle,'column',7:16)
            addStyle(app.UITable2,fontstyleAl,'column', 7:16)
            
            app.UITable2.ColumnName = InstSens.Properties.VariableNames;
            
            %Dati pos short
            app.UITable3.ColumnName = InstSens.Properties.VariableNames;
            app.UITable3.Data = InstSens(InstPrice < 0,:);
              HeightTable = height(app.UITable3.Data);
            c3 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c3(i,:));
                s.FontColor = c3(i,:);              
                addStyle(app.UITable3,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable3,fontstyle,'column',7:16)
            addStyle(app.UITable3,fontstyleAl,'column', 7:16)
            
            %Dati globali Portafoglio


            TempPieShortData = -InstPrice(InstPrice <0);
            TempPieLongData = InstPrice(InstPrice > 0);
            
% PLOT VUOTI SE NON SONO PRESENTI STRUMENTI

            if isempty(TempPieLongData) == 1
                TempPieLongData = categorical("No Instruments");
            end
            
            if isempty(TempPieShortData) == 1
                TempPieShortData = categorical("No Instruments");
            end
           
% PLOT TORTE PRINCIPALI         
            PieLongValue = pie(app.UIAxes2,TempPieLongData);           
            colormap(app.UIAxes2,c2)
            
            PieShortValue = pie(app.UIAxes3,TempPieShortData);
            colormap(app.UIAxes3,c3)
            
            NumSegLong = length(PieLongValue);
            NumSegShort = length(PieShortValue);
            
            for i = 1:2:NumSegLong 
                PieLongValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegLong
                PieLongValue(k).Color = 'w';
            end
            
             for i = 1:2:NumSegShort 
                PieShortValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegShort
                PieShortValue(k).Color = 'w';
            end


%    If per creare i categorical per le torte. La torta riporta il
%    totale della categoria in rapporto al totale delle opzioni,
%    non al prezzo


% RICHIAMO FUNZIONE ANALISI DATI SULLA TIPOLOGIA DELLE OPZIONI E CREAZIONE
% VETTORI PER I PIE CHARTS
[QSHORT, QLONG, LabelsLong, LabelsShort] = OptionsTypeRegrouping(app.LongInstrumentCounter,app.ShortInstrumentCounter,app.OptionTypeCategoricalLong, ...
    app.LongCategories_Quantities, app.OptionTypeCategoricalShort, app.ShortCategories_Quantities);


%       If per evitare errori per mancanza di dati nei pie chart
            
           if app.LongInstrumentCounter > 0
              PieCatLong = pie(app.UIAxes4,rmmissing(QLONG),[],LabelsLong);
              colormap(app.UIAxes4,"autumn")
              NumSegLong = length(PieCatLong);
                for i = 1:2:NumSegLong 
                    PieCatLong(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegLong
                    PieCatLong(k).Color = 'w';
                end
              
           end


            if app.ShortInstrumentCounter > 0
                 PieCatShort = pie(app.UIAxes5,rmmissing(QSHORT),[],LabelsShort);
                 colormap(app.UIAxes5,"autumn")                 
                 NumSegShort = length(PieCatShort); 
                for i = 1:2:NumSegShort 
                    PieCatShort(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegShort
                    PieCatShort(k).Color = 'w';
                end
           
            end


        end
        
        
% SCARICA DATI SERIE STORICHE E CREAZIONE TABELLE CONGIUNTE PREZZI E RENDIMENTI        
        function  TimeSeriesDataImport(app)
            url = 'https://fred.stlouisfed.org/';
            c = fred(url);
            
            c.DataReturnFormat = 'table';
            c.DatetimeType = 'datetime';


% Fetch all available daily foreign exchange rates between the US dollar and the Euro using the series 'DEXUSEU'. The table d contains one row for the series. Each variable describes a piece of information about the series.

            series = {'DEXUSAL' 'DEXUSEU' 'DEXUSUK'};
            SeriesNames = {'USD/AUD' 'EUR/USD' 'USD/GBP' };
           
            d = fetch(c,series);
            
            app.FredData = d.Data;
           
            close(c)

        end
        
        
        function YahooData(app)
            
            series2 = {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'};
            symbols = {'AUDGBP=X' 'EURGBP=X' 'AUDEUR=X'};
            
            value = 0;
            for i = 1:length(symbols)
                d.Value = value + i/length(symbols);
            data = getMarketDataViaYahooNew(symbols{i},datetime("01-01-1970","Format","dd-MM-uuuu"));
            data.Var1 = data.Date;
            data.Var2 = data.Close;
            app.FredData{i+3} = data(:,{ 'Var1' 'Var2'});
            end
            
            
              for i = 1:length(app.FredData)
                data = app.FredData{i};
                app.Prices{i} = data.Var2;
                app.Ret{i} = dlog((app.Prices{i}));
            end

%     UNIONE DELLE DATE PER CREARE UNA TABELLA UNICA DI PREZZI E RENDIMENTI            
            
            UniqueDates = unique([app.FredData{1}.Var1 ; app.FredData{2}.Var1 ; app.FredData{3}.Var1 ; app.FredData{4}.Var1 ; app.FredData{5}.Var1 ; app.FredData{6}.Var1]);
            [~, in1] = intersect(UniqueDates,app.FredData{1}.Var1);
            [~, in2] = intersect(UniqueDates,app.FredData{2}.Var1);
            [~, in3] = intersect(UniqueDates,app.FredData{3}.Var1);
            [~, in4] = intersect(UniqueDates,app.FredData{4}.Var1);
            [~, in5] = intersect(UniqueDates,app.FredData{5}.Var1);
            [~, in6] = intersect(UniqueDates,app.FredData{6}.Var1);
            
            
             TempPrice_TABLE = table();
             TempPrice_TABLE.Date = UniqueDates;
             TempPrice_TABLE.Var1(in1) = app.Prices{1};
             TempPrice_TABLE.Var2(in2) = app.Prices{2};
             TempPrice_TABLE.Var3(in3) = app.Prices{3};
             TempPrice_TABLE.Var4(in4) = app.Prices{4};
             TempPrice_TABLE.Var5(in5) = app.Prices{5};
             TempPrice_TABLE.Var6(in6) = app.Prices{6};
            
             TempRet_TABLE = table();
             TempRet_TABLE.Date = UniqueDates;
             TempRet_TABLE.Var1(in1) = app.Ret{1};
             TempRet_TABLE.Var2(in2) = app.Ret{2};
             TempRet_TABLE.Var3(in3) = app.Ret{3};
             TempRet_TABLE.Var4(in4) = app.Ret{4};
             TempRet_TABLE.Var5(in5) = app.Ret{5};
             TempRet_TABLE.Var6(in6) = app.Ret{6};
    
% ELIMINAZIONE 0 E TRASFORMAZIONE IN NaN             
             TempRetMatrix = table2array(TempRet_TABLE(:,2:end));
             
               
                index = TempRetMatrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempRetMatrix(row(i),col(i)) = nan;
                    end
                end
                
                
                TempRet_TABLE.Date = UniqueDates;
                TempRet_TABLE(:,2:7) = array2table(TempRetMatrix);
% TRASFORMAZIONE PREZZI IN TABELLA UNICA

                TempMatrixPrice = table2array(TempPrice_TABLE(:,2:end));
             
               
                index = TempMatrixPrice == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempMatrixPrice(row(i),col(i)) = nan;
                    end
                end
                
                TempPrice_TABLE.Date = UniqueDates;
                TempPrice_TABLE(:,2:7) = array2table(TempMatrixPrice);
                
           
            app.RetTable = TempRet_TABLE;
            app.RetMatrix = TempRetMatrix;
            app.PriceMatrix = TempMatrixPrice;
            app.PriceTable = TempPrice_TABLE;


        end
            
        
        function TimeSeriesPlotUpdate(app)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait: Starting Time Series Tab','Message','Updating Chart'); 


            d.Value = 0.40;
            d.Message = 'Retrieving Data';
            
            if app.Series2DropDown.Value == 0
                SelectedData1 = app.FredData{app.Series1DropDown.Value};
                Min_Date1 = min(SelectedData1{:,1});
                Min_Date2 = datetime("today");
            else
                SelectedData1 = app.FredData{app.Series1DropDown.Value};
                Min_Date1 = min(SelectedData1{:,1});
                SelectedData2 = app.FredData{app.Series2DropDown.Value};
                Min_Date2 = min(SelectedData2{:,1});
            end
            
            
            Min_Gen = min(Min_Date2,Min_Date1);
            app.StartDateDatePicker.Limits = [Min_Gen datetime("today")-1];
            
            d.Value = 0.5;
            d.Message = 'Generating Charts';
            
            
            if app.Series2DropDown.Value == 0
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.15 0.15 0.15];
                yyaxis(app.UIAxes6,"left")               
            
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
                
            else if app.Series1DropDown.Value == app.Series2DropDown.Value
                    yyaxis(app.UIAxes6,"right")
                    cla(app.UIAxes6)
                    app.UIAxes6.YColor = [0.15 0.15 0.15];
                    yyaxis(app.UIAxes6,"left")
                    plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
            else
                               
                FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
                
                d.Value = 0.6;
                d.Message = 'Generating Charts';


                yyaxis(app.UIAxes6,'left')
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
                ylabel(app.UIAxes6,FXNAME{app.Series1DropDown.Value})
                
                
                d.Value = 0.85;
                d.Message = 'Generating Charts';
                
                
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.85 0.33 .1]
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series2DropDown.Value+1})
                ylabel(app.UIAxes6,FXNAME{app.Series2DropDown.Value})
                
                
                end
            end
            
            
                d.Value = 1;
                d.Message = 'Charts Generated Successfully';


        end


%     HEATMAP
        
        
        function Heatmap(app)

% LIMITS SETTING            
            app.StartDateDatePicker_2.Limits = app.StartDateDatePicker.Limits;
            app.EndDateDatePicker_2.Limits = [app.StartDateDatePicker_2.Value+1 datetime("today")];

%CREAZIONE DI UNA TABELLA TEMPORANEA CONTENENTE I RENDIMENTI SELEZIONATI

            index = app.RetTable.Date > app.StartDateDatePicker_2.Value & app.RetTable.Date < app.EndDateDatePicker_2.Value;
            TempMatrix = app.RetMatrix(index,:);
            FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            
            if isempty(rmmissing(TempMatrix)) == 1
                app.TextArea.FontColor = 'r';
                app.TextArea.FontWeight = 'bold';
                app.TextArea.Value = ["CORRELATION MATRIX WARNING:"];
                pause(5)
                app.TextArea.FontWeight = 'normal';
                app.TextArea.Value = [ "Not enough data available for all currencies into the date range choosen. Please, try a bigger interval."];
                pause(5)
                app.TextArea.Value = "";
                
            else
                corrMatr = corr(rmmissing(TempMatrix));
                heatmap(app.CorrelationMapPanel,FXNAME,FXNAME,corrMatr,"ColorbarVisible","on","Colormap",autumn(8))
            
            end


        end
            
        
        function  HistTimeSeries(app)
            
            
            %GEnerazione Istogramma
                app.StartDateDatePicker_3.Limits = [min(app.RetTable.Date) max(app.RetTable.Date)];
                histData = app.RetTable{app.RetTable.Date > app.StartDateDatePicker_3.Value,app.CurrencyDropDown.Value+1}; 
            
                histogram(app.UIAxes7,histData,'FaceColor','r','EdgeColor','w',"BinEdges",linspace(-0.04,0.04,75),"Normalization","pdf");
                xlim = [-0.04 0.04];
                
                hold(app.UIAxes7,"on")


            if app.OverlayDistributionCheckBox.Value == true
                app.DistributionTypeDropDown.Enable = 'on';


                x = linspace(-0.05,0.05,500);
                if app.DistributionTypeDropDown.Value == 1
                    pd = fitdist(histData,'Normal');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["Normal Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)])];
                else
                    
                    pd = fitdist(histData,'tLocationScale');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["T-Student Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)]), strjoin(["nu = " string(pd.nu)])];
                end
                
            else
                app.DistributionTypeDropDown.Enable = 'off';
                app.TextArea.Value ="";
                
            end
            
            hold(app.UIAxes7,"off")
            
        end


        function SummaryStatistics(app)
            %% HeatMap e Summary statistic
            
            
            index = app.RetTable.Date > app.StartDateDatePicker_2.Value & app.RetTable.Date < app.EndDateDatePicker_2.Value;
            TempMatrix = app.RetMatrix(index,:);
            
            qvec = [0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99];
               
                meanv = mean(rmmissing(TempMatrix));
                vartt = var(rmmissing(TempMatrix));
                maxtt = max(rmmissing(TempMatrix));
                mintt = min(rmmissing(TempMatrix));
                stdtt = std(rmmissing(TempMatrix));
                skewtt = skewness(rmmissing(TempMatrix));
                kurtosistt = kurtosis(rmmissing(TempMatrix));
                
                quant_tt = quantile(rmmissing(TempMatrix),qvec)';
            
            
            tempTable = table(meanv',vartt',stdtt',maxtt',mintt',skewtt',kurtosistt');
            app.UITable5.Data = tempTable;
            
            for i = 1:length(quant_tt)
            tempTable{:,i+7} = quant_tt(:,i);
            end
            
            FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            
            for i = 1:length(qvec)
                quantileNames(i) = (strjoin(["Q." num2str(qvec(i))]));
            end
            
            
            app.UITable5.Data = tempTable;
            app.UITable5.RowName = FXNAME;
            app.UITable5.ColumnName = ["Mean" "Variance" "Std Dev" "Max" "Min" "Skew" "Kurtosis" quantileNames];
            
        end
    
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            pause(5)
            d = uiprogressdlg(app.UIFigure,"Message",'Starting Dashboard',"Value",0,"Title",'Please Wait: Dashboard Starting');
          
          
            %Disabilita i campi inserimento non per la plain vanilla
            app.BarrierValueEditField.Enable = 'off';
            app.BinaryValueEditField.Enable = 'off';
            app.BarrierTypeDropDown.Enable = 'off';
            app.OptionMaturityDatePicker.Limits = [datetime('01-01-1999',"Format","dd-MM-uuuu") datetime('01-01-2100',"Format","dd-MM-uuuu")];
            app.EndDateDatePicker.Value = datetime("today");
            app.Series1DropDown.ItemsData = [1 2 3 4 5 6];
            app.Series2DropDown.ItemsData = [0 1 2 3 4 5 6];
            app.EndDateDatePicker_3.Value = datetime("today");
            app.StartDateDatePicker_4.Value = app.EndDateDatePicker_3.Value-90;
            
 
            d.Value = .20;
            d.Message = 'Initializing Charts';
            %Grafici a torta inizializzazione
            ReSetHomePlots(app)
            
            
            %imposta formato decimali
            format bank;
            d.Value = .30;
            d.Message = ['Getting Data from Yahoo Finance and FRED ' char(174)];
            
            
            %Plot dati cambio su grafico
            TimeSeriesDataImport(app);
            YahooData(app)
            TimeSeriesPlotUpdate(app);
           
            d.Value = .40; 
           d.Message = 'Creating Correlation Heatmap';
            
% Call Generating Function
            Heatmap(app);            
            
            d.Value = .7;
            d.Message = 'Generating Summary Statistics';
            
            SummaryStatistics(app)

            
% Generate Return Distribution
            
            HistTimeSeries(app)

            d.Value = 1;
            d.Message = 'Operations Completed';
            pause(0.5)
            
            
            app.RelevantInformationsTextArea.Value = ["Welcome into Risk Management Dashboard for Option Trading Desks v1.0." "Start inserting rates and settle data. Insert transactions and confirm portfolio pressing ''Update Portfolio'' Button. "];
        end

        % Button pushed function: AddRegistrationButton
        function AddRegistrationButtonPushed(app, event)
            app.PortfolioBackup = BackupLastTransaction(app.Instr,app.Q,app.Pricer, app.counter,app.counter2,app.TestInstr,app.ShortInstrumentCounter, ...
                app.LongInstrumentCounter,app.OptionTypeCategoricalLong,app.OptionTypeCategoricalShort,app.LongCategories_Quantities,app.ShortCategories_Quantities, ...
                app.OptionTypeCategorical,app.Currency,app.InstSens,app.PortSens,app.InstPrice);
            
            
            app.counter = app.counter +1;
             d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Adding Option to Portfolio'); 
                       
             
%             Le Variabili ricevono i dati dagli elementi interattivi della
%             %dashboard
            OptionType = app.InstrumentTypeDropDown.Value;
            Strike = app.StrikeEditField.Value;
            LongShort = app.PositionButtonGroup.SelectedObject.Text;
            CallPut = app.OptionTypeButtonGroup.SelectedObject.Text;
            BinaryValue = app.BinaryValueEditField.Value;
            BarrierValue = app.BarrierValueEditField.Value;
            BarrierType = app.BarrierTypeDropDown.Value;
            MaturityDate = app.OptionMaturityDatePicker.Value;
            OptionStyle = app.OptionStyleButtonGroup.SelectedObject.Text;
            class(OptionStyle)


d.Value = .33; 
d.Message = 'Creating Portfolio';


%             If per cambio segno alla quantità per avere posizioni long e
%             short
            Test = 'Long';
            if strcmp(LongShort,Test) == 1
                app.Q = [app.Q app.QuantitySpinner.Value]; %riferimento a property perchè già esistente
                app.OptionTypeCategoricalLong = [app.OptionTypeCategoricalLong string(OptionType)];
                app.LongCategories_Quantities = [app.LongCategories_Quantities app.QuantitySpinner.Value];
                
                app.LongInstrumentCounter = app.LongInstrumentCounter +1;
            else
                app.Q = [app.Q -app.QuantitySpinner.Value];
                app.OptionTypeCategoricalShort = [app.OptionTypeCategoricalShort string(OptionType)];
                app.ShortCategories_Quantities = [app.ShortCategories_Quantities app.QuantitySpinner.Value];
                
                app.ShortInstrumentCounter = app.ShortInstrumentCounter+1;
                LongShort = 'Short';
            end
            
            % STORE Q TO DISPLAY TRANSACTION MESSAGE
            MessageQ = app.Q(end);


%             Stringa con nome strumento
            Name = OptionType;
            app.OptionTypeCategorical = [app.OptionTypeCategorical categorical(upper(cellstr(OptionType)))];
            
%             Vari if per definire lo strumento da aggiungere
            if strcmp(OptionType,'Vanilla') == 1
            app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate,'ExerciseStyle',OptionStyle, 'Name',Name)];
            
            end
            
            if strcmp(OptionType,'Binary') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"PayoffValue",BinaryValue,"Strike",Strike,"ExerciseDate", MaturityDate,'Name',Name)];
            end
            
            if strcmp(OptionType,'Barrier') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"BarrierValue",BarrierValue,"BarrierType",BarrierType,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
            
            if strcmp(OptionType, 'Lookback') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
           
            
     d.Value = .38; 
    d.Message = 'Option Added';       


%             Costruzione Pricer, anteposta per controllo degli strumenti ed
%             eliminazione
            Settle = app.Settle;


            BLSModel = finmodel("BlackScholes","Volatility",app.VolatilityEditField.Value);


% EUR CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'EUR'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveEUR,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveEUR,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

% USD CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'USD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveUSD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveUSD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

            
% AUD CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'AUD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveAUD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveAUD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
% GBP CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'GBP'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveGBP,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveGBP,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
   
d.Value = .45; 
    d.Message = 'Pricing Model Created';            
            
            
           app.Currency = [app.Currency string(app.UnderlyingDropDown.Value)];
           
    
            app.TestInstr(1) = 0;
            if app.counter == 1
            else 
                for i = 1:app.counter-app.counter2-1
                    if (app.Instr(i) == app.Instr(app.counter-app.counter2)) & (app.Q(i)*app.Q(app.counter-app.counter2) > 0) & (app.Currency(i) == app.UnderlyingDropDown.Value)
                        app.TestInstr(i) = 1;
                    else
                        app.TestInstr(i) = 0;
                    end
                end
            end
            
            if nnz(app.TestInstr) > 0
                app.Currency(app.counter-app.counter2) = [];
                app.Instr(app.counter-app.counter2) = [];
                app.Pricer(app.counter-app.counter2) = [];
                app.Q(logical([app.TestInstr 0])) = app.Q(logical([app.TestInstr 0]))+ app.Q(app.counter-app.counter2);
                app.Q(app.counter-app.counter2) = [];
                app.OptionTypeCategorical(app.counter-app.counter2) = [];
                app.counter2 = app.counter2+1;
            end


if strcmp(OptionStyle,'American') == 1            
d.Value = .55; 
    d.Message = 'Simulating Underlying Paths';
else
    d.Value = .55; 
    d.Message = 'Black-Scholes Pricing';
end            
            
            Qty = app.Q;
            Instrum = app.Instr;
            
%             
%             Costruzione Pricer
%             

            
            [~, app.InstPrice, app.PortSens, app.InstSens] = PortCreationPricing(app,Instrum,Qty,app.Pricer);


if strcmp(OptionStyle,'American') == 1            
d.Value = .75; 
    d.Message = 'Simulation Completed';
else
    d.Value = .75; 
    d.Message = 'Option Correctly Priced';          
end            
            
%      Creazione Variabili info strumenti       
            for i = 1: length(app.Instr)
                OptionTypetoTABLE(i,1) = categorical(upper(app.Instr(i).OptionType));
                StriketoTABLE(i,1) = app.Instr(i).Strike;
                StyletoTABLE(i,1) = app.Instr(i).ExerciseStyle;
                DatetoTABLE(i,1) = app.Instr(i).ExerciseDate;
            
                if strcmp(app.OptionTypeCategorical(1,i),'BARRIER') == 1 %Maiuscolo perchè sopra c'è upper nella creazione del vettore
                    BValuetoTABLE(i,1) = app.Instr(i).BarrierValue;
                    BTypetoTABLE(i,1) = app.Instr(i).BarrierType;
                else
                    BValuetoTABLE(i,1) = '-';
                    BTypetoTABLE(i,1) = '-';
                
                end
                
                if strcmp(app.OptionTypeCategorical(1,i),'BINARY') == 1
                    BinVatoTABLE(i,1) = app.Instr(i).BinaryValue;
                else
                    BinVatoTABLE(i,1) = '-';
                end


            end
%     Creazione colonne nelle tabelle 
            app.InstSens.Quantity = app.Q';
            app.InstSens.Currency = categorical(app.Currency');
            app.InstSens.OptionType = app.OptionTypeCategorical';
            app.InstSens.Style = upper(StyletoTABLE);
            app.InstSens.CallPut = OptionTypetoTABLE;
            app.InstSens.Strike = StriketoTABLE;
            app.InstSens.BarrierValue = BValuetoTABLE;
            app.InstSens.BarrierType = BTypetoTABLE;
            app.InstSens.BinaryValue = BinVatoTABLE;
            app.InstSens.Maturity = DatetoTABLE;
            app.InstSens.Legend = zeros([height(app.InstSens(:,1)) 1]);
            app.InstSens.Lambda = [];
            app.InstSens.Properties.RowNames = {};


    d.Value = .95; 
    d.Message = 'Generating Tables and Charts';             


%       Update Plots
            
            UpdateHomePlots(app,app.InstPrice,app.InstSens,app.PortSens)


    d.Value = 1; 
    d.Message = 'Transaction Registered'; 
    close(d)
    pause(1)
    
    
    % TRANSACTION MESSAGE BLINKING
    app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
    app.RelevantInformationsTextArea.Value = ["Transaction registered correctly:" strjoin([string(MessageQ) " " app.Currency(end) " " OptionType " " OptionStyle " " upper(app.Instr(end).OptionType) " Strike "  app.Instr(end).Strike])];
    pause(0.25)
    app.RelevantInformationsTextArea.Value = "";
    pause(0.25)
    app.RelevantInformationsTextArea.Value = ["Transaction registered correctly:" strjoin([string(MessageQ) " " app.Currency(end) " " OptionType " " OptionStyle " " upper(app.Instr(end).OptionType) " Strike "  app.Instr(end).Strike])];
    pause(0.25)
    app.RelevantInformationsTextArea.Value = "";
    pause(0.25)
    app.RelevantInformationsTextArea.Value = ["Transaction registered correctly:" strjoin([string(MessageQ) " " app.Currency(end) " " OptionType " " OptionStyle " " upper(app.Instr(end).OptionType) " Strike "  app.Instr(end).Strike])];
    pause(0.25)
    app.RelevantInformationsTextArea.Value = "";
    pause(0.25)
    app.RelevantInformationsTextArea.Value = ["Transaction registered correctly:" strjoin([string(MessageQ) " " app.Currency(end) " " OptionType " " OptionStyle " " upper(app.Instr(end).OptionType) " Strike "  app.Instr(end).Strike])];
    pause(0.25)
    app.RelevantInformationsTextArea.Value = "";
    pause(0.25)
    app.RelevantInformationsTextArea.Value = ["Transaction registered correctly:" strjoin([string(MessageQ) " " app.Currency(end) " " OptionType " " OptionStyle " " upper(app.Instr(end).OptionType) " Strike "  app.Instr(end).Strike])];
   
    
    app.UpdatePortfolioButton.Enable = 'on';
        end

        % Value changed function: InstrumentTypeDropDown
        function InstrumentTypeDropDownValueChanged(app, event)
            value = app.InstrumentTypeDropDown.Value;
            if strcmp(value,'Barrier') == 1
                app.BarrierValueEditField.Enable = 'on';
                app.BarrierTypeDropDown.Enable = 'on';
            else
                app.BarrierValueEditField.Enable = 'off'; 
                app.BarrierTypeDropDown.Enable = 'off';
            end
            
            
            if strcmp(value,'Binary') == 1
                app.BinaryValueEditField.Enable = 'on';
                app.OptionStyleButtonGroup.Enable = 'off';
                app.EuropeanButton.Value = true;
            else
                app.BinaryValueEditField.Enable = 'off';
                app.OptionStyleButtonGroup.Enable = 'on';
            end
            
            if strcmp(value,'Lookback') == 1
                app.SimulationTrialsEditField.Enable  = 'on';
            else
                app.SimulationTrialsEditField.Enable = 'off';
            end
        end

        % Button pushed function: DeleteAllRegistrationsButton
        function DeleteAllRegistrationsButtonPushed(app, event)
            app.Instr = [];
            app.Q = [];
            app.Pricer = [];
            app.counter = 0;
            app.counter2 = 0;
            app.TestInstr = [];
            app.ShortInstrumentCounter = 0;
            app.LongInstrumentCounter = 0;
            app.OptionTypeCategoricalLong = [];
            app.OptionTypeCategoricalShort = [];
            app.LongCategories_Quantities = [];
            app.ShortCategories_Quantities = [];
            app.OptionTypeCategorical = [];
            app.Currency = [];
%        Reset Plots and Tables
            ReSetHomePlots(app)
            
        end

        % Button pushed function: SetDateandRatesButton
        function SetDateandRatesButtonPushed(app, event)
            app.SetDateandRatesButton.Enable = 'off';
            app.RateDialog = RateandSettleDialog(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.RateDialog)
            delete(app.CurrencySetError)
            delete(app)


        end

        % Value changed function: UnderlyingDropDown
        function UnderlyingDropDownValueChanged(app, event)
            value = app.UnderlyingDropDown.Value(1:3);
            
% IMPOSTAZIONE DELL?UNDERLYING PRICE COME ULTIMO TASSO DI CAMBIO REGISTRATO
            
           TEST = RateCurveTEST(app.ZeroCurveEUR,app.ZeroCurveUSD,app.ZeroCurveAUD,app.ZeroCurveGBP);


% %% INSERIRE QARNING IN TEXT AREA           
            if TEST(1) == 0 & value == 'EUR'
                app.RelevantInformationsTextArea.Value = "There are no EUR rates data. Select another underlying or press ''Set Date and Rates Button' to set EUR rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(1) == 1 & value == 'EUR'
                app.RelevantInformationsTextArea.Value = "EUR rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            if TEST(2) == 0 & value == 'USD'
               app.RelevantInformationsTextArea.Value = "There are no USD rates data. Select another underlying or press ''Set Date and Rates Button' to set USD rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(2) == 1 & value == 'USD'
                app.RelevantInformationsTextArea.Value = "USD rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            
            if TEST(3) == 0 & value == 'AUD'
               app.RelevantInformationsTextArea.Value = "There are no AUD rates data. Select another underlying or press ''Set Date and Rates Button' to set AUD rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(3) == 1 & value == 'AUD'
                app.RelevantInformationsTextArea.Value = "AUD rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end
            
            
            if TEST(4) == 0 & value == 'GBP'
               app.RelevantInformationsTextArea.Value = "There are no GBP rates data. Select another underlying or press ''Set Date and Rates Button' to set GBP rates.";
                app.RelevantInformationsTextArea.FontColor = [255/255 165/255 0];
                app.AddRegistrationButton.Enable = 'off';
                app.UpdatePortfolioButton.Enable = 'off';
                
            elseif TEST(3) == 1 & value == 'GBP'
                app.RelevantInformationsTextArea.Value = "GBP rates data available.";
                app.RelevantInformationsTextArea.FontColor = [0 0.6 0];
                app.AddRegistrationButton.Enable = 'on';
            end


        end

        % Value changed function: Series2DropDown
        function Series2DropDownValueChanged(app, event)
            value = app.Series2DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: Series1DropDown
        function Series1DropDownValueChanged(app, event)
            value = app.Series1DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            value = app.StartDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            value = app.EndDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: StartDateDatePicker_2
        function StartDateDatePicker_2ValueChanged(app, event)
            value = app.StartDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: EndDateDatePicker_2
        function EndDateDatePicker_2ValueChanged(app, event)
            value = app.EndDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: BackgroundColorButton
        function BackgroundColorButtonValueChanged(app, event)
            value = app.BackgroundColorButton.Value;
            if value == true
                app.BackgroundColorButton.BackgroundColor = [0.15 0.15 0.15];
                app.BackgroundColorButton.FontColor = [1 1 1];
                app.CorrelationMapPanel.ForegroundColor = [0 0 0];
                app.CorrelationMapPanel.BackgroundColor = [1 1 1];
            else
                app.BackgroundColorButton.BackgroundColor = [1 1 1];
                app.BackgroundColorButton.FontColor = [0 0 0];
                app.CorrelationMapPanel.ForegroundColor = [1 1 1];
                app.CorrelationMapPanel.BackgroundColor = [0.15 0.15 0.15];
            end
            
        end

        % Value changed function: CurrencyDropDown
        function CurrencyDropDownValueChanged(app, event)
            value = app.CurrencyDropDown.Value;
            HistTimeSeries(app)
        end

        % Value changed function: StartDateDatePicker_3
        function StartDateDatePicker_3ValueChanged(app, event)
            value = app.StartDateDatePicker_3.Value;
            HistTimeSeries(app)
        end

        % Value changed function: OverlayDistributionCheckBox
        function OverlayDistributionCheckBoxValueChanged(app, event)
            value = app.OverlayDistributionCheckBox.Value;
            
            HistTimeSeries(app)
            
            
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            
           HistTimeSeries(app)
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.RiskHorizonEditField.Value;
            HistTimeSeries(app)
        end

        % Selection changed function: OptionStyleButtonGroup
        function OptionStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionStyleButtonGroup.SelectedObject.Text;
            
            if selectedButton == 'American'
                app.SimulationTrialsEditField.Enable = 'on';
            else
                app.SimulationTrialsEditField.Enable = 'off';
            end
        end

        % Button pushed function: GenerateStepladderButton
        function GenerateStepladderButtonPushed(app, event)
d = uiprogressdlg(app.UIFigure,"Title","Please Wait. Generating Stepladder","Message","Obtaining portfolio data","Value",0);


[Instr,QQ, Pricer, Currency] = ReadLatestPortfolio(app.PortfolioData);

            
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(Instr)
Portfolio{1,i} = Instr(i);
Portfolio{2,i} = QQ(i);
Portfolio{3,i} = Pricer(i);
Portfolio{4,i} = string(char(Currency(i)));
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end

%CREAZIONE DEL PORTAFOGLIO CON LE OPZIONI RICHIESTE
Temp_Portfolio = Portfolio(1:3,logical(TESTV));

%CONVERSIONE IN UN PORTAFOGLIO CHE PUò ESSERE PREZZATO CON FINPORTFOLIO
for i = 1:size(Temp_Portfolio,2)
    TempInstruments(1,i) = Temp_Portfolio{1,i}; 
    TempQuantities(1,i) = Temp_Portfolio{2,i};
    TempPricer(1,i) = Temp_Portfolio{3,i};
end

d.Message = "Creating Stepladder";
d.Value = 0.2;


                Idx = app.RetTable.Date > datetime("today")-calmonths(app.SamplingPeriodDropDown.Value);
                TempMatrix = app.RetMatrix(Idx,app.StepladderCurrencyDropDown.Value);
                
                
                LastPrice = app.PriceMatrix(end,app.StepladderCurrencyDropDown.Value);
                if isnan(LastPrice) == 1
                    LastPrice = app.Prices{app.StepladderCurrencyDropDown.Value}(end);
                end
                RiskHorizonFactor = sqrt(app.RiskHorizonEditField.Value);    
                sq = std(rmmissing(TempMatrix)); % STANDARD DEVIATION OF SELECTED RETURNS


d.Message ="Obtaining quantiles";
%CREAZIONE SCENARI
qvec = [0.001 0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99 0.999];
Quantili = quantile(rmmissing(TempMatrix),qvec);

VarESQvec = linspace(0.001,0.999,1000)';
QuantiliVAR = quantile(rmmissing(TempMatrix),VarESQvec);

S0Scenery= exp(Quantili.*RiskHorizonFactor)'*LastPrice;
app.TextArea_2.Value = ["Current Underlying Price:" string(LastPrice)];


%APPLICAZIONE DEGLI SCENARI AI PRICER E AGGIUNTA DI UNO SCENARIO
%CORRISPONDENTE AL VALORE SPOT DEL TASSO DI CAMBIO (PORTAFOGLIO INIZIALE)

S0Scenery_StartingPrice = [S0Scenery ; LastPrice];

zero = zeros([length(S0Scenery_StartingPrice) 1]);
app.SummaryTable = table(zero, zero, zero, zero, zero, zero, zero, 'VariableNames',{'Price' 'Delta' 'Greca2' 'Greca3' 'Greca4' 'Greca5' 'Greca6'});
 % PREALLOCAZIONE

d.Message = "Generating portfolio price scenarios" ;
d.Value = 0.4;
for i = 1:length(S0Scenery_StartingPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = S0Scenery_StartingPrice(i);
    end
    [~, ~, PortSens, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    app.SummaryTable(i,:) = PortSens;
    
end

Loss = table(app.SummaryTable{1:end-1,1}-app.SummaryTable{end,1},'VariableNames',"Loss");
Tmp_TABLE = [table(S0Scenery,'VariableNames',"Scenery") table(log(S0Scenery_StartingPrice(1:end-1)./S0Scenery_StartingPrice(end)),'VariableNames',"Variation") Loss app.SummaryTable(1:end-1,:)];

%ADDING BACKGROUND TO GAINS AND LOSSES

[Sorted , indexes] = sort(Tmp_TABLE{:,3});
[PRR, ~ ] = find(Sorted < 0);
[PRG, ~] = find(Sorted > 0);

RedCol = [linspace(0.4,0.8,length(qvec))' zeros([length(qvec) 2])];
GreenCol = [ zeros([length(qvec) 1]) linspace(0.8,0.4,length(qvec))' zeros([length(qvec) 1])];

colInR = round(linspace(1,size(RedCol,1),length(PRR)))';
colInG = round(linspace(1,size(GreenCol,1),length(PRG)))';


for i = 1:length(PRR)
    
    s = uistyle("BackgroundColor",RedCol(colInR(i),:));
    addStyle(app.UITable6,s,'cell',[indexes(PRR(i)) 3]);
end

for i = 1:length(PRG)
    
    s = uistyle("BackgroundColor",GreenCol(colInG(i),:));
    addStyle(app.UITable6,s,'cell',[indexes(PRG(i)) 3]);
end


app.UITable6.Data = Tmp_TABLE;
app.UITable6.ColumnName = {'Scenarios' 'Foreign Exchange Var. (%)' 'Gains/Losses'  ' Portfolio Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};


app.UITable7.Data = app.SummaryTable(end,:);
app.UITable7.ColumnName = {'Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};
app.UITable7.RowName = {'Selected Currency Portfolio Data'};


d.Message = "Creating VaR and ES scenarios";
d.Value = 0.7;
%VAR ES TABLE STATISTICAL APPROACH 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
[ES, VAR, PortfolioReturns, InitialValue] = Varstepladder(LastPrice, RiskHorizonFactor, QuantiliVAR,TempPricer,TempInstruments, TempQuantities,d,1,1);


Alpha = ["99.9%" "99%" "95%"];
ScenarioRetVAR = VAR./InitialValue;
ScenarioRetES = ES./InitialValue;


app.UITable8.Data = table(string(Alpha'),ScenarioRetVAR', abs(VAR)', ScenarioRetES' , abs(ES)' ,'VariableNames',{'Alpha' 'VaR Scenario' 'VaR Applied Shock (%)'  'VaR' 'ES Scenario' });
app.UITable8.ColumnName = {'Confidence Intervals' 'VaR Scenario Return' 'VaR'   'ES Scenario Return' 'ES' };

addStyle(app.UITable8,uistyle("HorizontalAlignment",'center'),'column',1:7);

d.Value = 1;
d.Message = "Stepladder created";
pause(0.5)


VaRMessage = app.DeskVaRLimitEditField.Value < abs(VAR);
if app.DeskVaRLimitEditField.Value == 0.001
    app.TextArea_2.Value = ["VaR limit not set. Set limit to get informations on VaR breach" ["Current Underlying Price:" string(LastPrice)]];
    app.TextArea_2.FontColor = [255/255 165/255 0];
else
if nnz(VaRMessage) == 0
    app.TextArea_2.Value = ["Current portfolio doesn't breach limit set" ["Current Underlying Price:" string(LastPrice)]]; 
    app.TextArea_2.FontColor = [0 0.6 0];
end
if nnz(VaRMessage) == 1
   app.TextArea_2.Value = ["Current portfolio breaches VaR limit at significance level 95%" ["Current Underlying Price:" string(LastPrice)]];
   app.TextArea_2.FontWeight = 'bold';
   app.TextArea_2.FontColor = 'r';
 
end
if nnz(VaRMessage) == 2
   app.TextArea_2.Value = ["Current portfolio breaches VaR limit at significance levels:" "95% 99%" ["Current Underlying Price:" string(LastPrice)]];
   app.TextArea_2.FontWeight = 'bold';
   app.TextArea_2.FontColor = 'r';
 
end
if nnz(VaRMessage) == 3
   app.TextArea_2.Value = ["Current portfolio breaches VaR limit at significance levels:" "95% 99% 99.9%" ["Current Underlying Price:" string(LastPrice)]]; 
   app.TextArea_2.FontWeight = 'bold';
   app.TextArea_2.FontColor = 'r';
 
end
end


        end

        % Button pushed function: StartBacktestingButton
        function StartBacktestingButtonPushed(app, event)
d = uiprogressdlg(app.UIFigure,"Title","Please Wait. Backtesting VaR","Message","Obtaining portfolio data","Value",0);
app.StartDateDatePicker_4.Value = app.VaRBacktestingHorizonDatePicker.Value+1;
app.EndDateDatePicker_3.Value = datetime("today");


[Instr,QQ, Pricer, Currency] = ReadLatestPortfolio(app.PortfolioData);

            
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(Instr)
Portfolio{1,i} = Instr(i);
Portfolio{2,i} = QQ(i);
Portfolio{3,i} = Pricer(i);
Portfolio{4,i} = string(char(Currency(i)));
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end
d.Value = 0.15;

%CREAZIONE DEL PORTAFOGLIO CON LE OPZIONI RICHIESTE
Temp_Portfolio = Portfolio(1:3,logical(TESTV));

%CONVERSIONE IN UN PORTAFOGLIO CHE PUò ESSERE PREZZATO CON FINPORTFOLIO
for i = 1:size(Temp_Portfolio,2)
    TempInstruments(1,i) = Temp_Portfolio{1,i}; 
    TempQuantities(1,i) = Temp_Portfolio{2,i};
    TempPricer(1,i) = Temp_Portfolio{3,i};
end

            
                        SelectedData1 = app.FredData{app.StepladderCurrencyDropDown.Value};
                        Min_Date1 = min(SelectedData1{:,1});
                        app.VaRBacktestingHorizonDatePicker.Limits = [Min_Date1 datetime("today")-1];


                         LastPrice = app.PriceMatrix(end,app.StepladderCurrencyDropDown.Value);
                        if isnan(LastPrice) == 1
                            LastPrice = app.Prices{app.StepladderCurrencyDropDown.Value}(end);
                        end
                        RiskHorizonFactor = sqrt(app.RiskHorizonEditField.Value);  
                        StartDate = app.VaRBacktestingHorizonDatePicker.Value; 
                        TestWindow = app.RetTable.Date(app.RetTable.Date > StartDate);

d.Message = "Calculating VaR on historical data";
d.Value = 0.2;
              
VarESQvec = linspace(0.001,0.999,1500)';
Qvec1 = norminv(VarESQvec);


F = 0;
for t = 1:length(TestWindow)
    time = TestWindow(t);
   F = F+1;
   
Prog = t;
TotLen = length(TestWindow);   
    
idx = app.RetTable.Date > time-calmonths(app.SamplingRollingWindowDropDown.Value) & app.RetTable.Date < time;
VARTempMatrix = app.RetMatrix(idx,app.StepladderCurrencyDropDown.Value);

sq = std(rmmissing(VARTempMatrix));
Quantili = sq.*Qvec1;


[ES, VAR, ~, ~] = Varstepladder(LastPrice,RiskHorizonFactor,Quantili,TempPricer,TempInstruments,TempQuantities,d,Prog, TotLen);


ES1(1:3,F) = abs(ES');
VAR1(1:3,F) = abs(VAR');

end
plot(app.UIAxes8,TestWindow,VAR1(3,:),TestWindow,VAR1(2,:),TestWindow,VAR1(1,:))
hold(app.UIAxes8,"on")


if app.DeskVaRLimitEditField.Value == 0.001
    hold(app.UIAxes8,"off")
    legend(app.UIAxes8,{'VaR 95' 'VaR 99' 'VaR 99.9'},'Location','Best',"Box","on","EdgeColor",'w',"TextColor",'w')
else
    plot(app.UIAxes8,TestWindow,repmat(app.DeskVaRLimitEditField.Value,1, size(VAR1,2)),'--')
    hold(app.UIAxes8,"off")
    legend(app.UIAxes8,{'VaR 95' 'VaR 99' 'VaR 99.9' 'Limit'},'Location','Best',"Box","on","EdgeColor",'w',"TextColor",'w')
end


d.Message = "Historical data processed";
pause(1)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%ù

d.Message = "Checking VaR exceptions and failures";
d.Value = 0;

%VAR LIMITS EXCEPTIONS


DateIndex = app.RetTable.Date >= TestWindow(1);
Excep_PRICE_Matrix = app.PriceMatrix(DateIndex,app.StepladderCurrencyDropDown.Value);

Logic = isnan(Excep_PRICE_Matrix); %Trova le righe dove sono situati gli nan
PricesToPricer = Excep_PRICE_Matrix(~Logic);

Dates_NaN = app.RetTable.Date(DateIndex);
Dates_NO_NaN = Dates_NaN(~Logic); % PRENDE LE DATE NON CORRISPONDENTI AD UN NAN         
 

d.Value = 0.5;

for i = 1:length(PricesToPricer)
    for k = 1:length(TempPricer)
        TempPricer(k).SpotPrice = PricesToPricer(i);
    end
    PortPrice = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    PortPriceArray(i,1) = PortPrice;
end

PortfolioGL = [0; diff(PortPriceArray(:,1))];

d.Value = 0.6;


ZoomInd   = (Dates_NO_NaN >= app.StartDateDatePicker_4.Value) & (Dates_NO_NaN <= app.EndDateDatePicker_3.Value);
VaR_NO_NaN = VAR1(:,~Logic')';
VaRData   = [-VaR_NO_NaN(ZoomInd,3) -VaR_NO_NaN(ZoomInd,2) -VaR_NO_NaN(ZoomInd,1)];
VaRFormat = {'-','--','-.'};
D = Dates_NO_NaN(ZoomInd);
R = PortfolioGL(ZoomInd);
N95 = VaRData(:,1);
N99 = VaRData(:,2);
N999 = VaRData(:,3);
IndN99    = (R < N99);
IndN999   = (R < N999);
IndN95 = (R < N95);

d.Value = 0.7;


bar(app.UIAxes9,D,R,0.5,'FaceColor',[0.7 0.7 0.7]);
hold(app.UIAxes9,"on")
for i = 1 : size(VaRData,2)
    stairs(app.UIAxes9,D-0.5,VaRData(:,i),VaRFormat{i});
end
ylabel(app.UIAxes9,'VaR')
xlabel(app.UIAxes9,'Date')
legend(app.UIAxes9,{'Portfolio Gains/Losses','VaR95','VaR99','VaR99.9'},'Location','Best','AutoUpdate','Off',"Box","on","EdgeColor",'w',"TextColor",'w')


d.Value = 0.9;


plot(app.UIAxes9,D(IndN95),N95(IndN95),'o',D(IndN99),N99(IndN99),'o',...
   D(IndN999),N999(IndN999),'o','MarkerSize',8,'LineWidth',1.5)
xlim(app.UIAxes9,[D(1)-1, D(end)+1])
hold(app.UIAxes9,"off")

d.Value = 1;
d.Message = "Operation completed";

SelectedCurrencyFX = app.StepladderCurrencyDropDown.Items(app.StepladderCurrencyDropDown.Value);

vbt = varbacktest(PortfolioGL,VaR_NO_NaN,"PortfolioID",SelectedCurrencyFX,"VaRLevel",[0.999 0.99 0.95]);

SummaryVB = summary(vbt);
app.UITable10.Data = SummaryVB;
app.UITable10.ColumnName = SummaryVB.Properties.VariableNames;
        end

        % Button pushed function: ImportOptionsDataButton
        function ImportOptionsDataButtonPushed(app, event)
            [file, path] = uigetfile();
            app.file = fullfile(path,file)
            
            
        end

        % Button pushed function: GenerateChartButton
        function GenerateChartButtonPushed(app, event)
            SPX = table2array(readtable(app.file));
            S0 = app.UnderlyingEditField.Value;
            r = app.RateEditField.Value;

            Maturity=SPX(3:end,2);          % maturity
            Strike=SPX(3:end,3);            % moneyness
            CallPrice=SPX(3:end,4);         % option prices


            s = VolSurface(app.UIAxes10,S0, r, Maturity, Strike, CallPrice);

        end

        % Button pushed function: UpdatePortfolioButton
        function UpdatePortfolioButtonPushed(app, event)


            app.StoreDataButtonCounter = app.StoreDataButtonCounter +1; 
            Counter = app.StoreDataButtonCounter;
            
            if Counter == 1
                if exist(app.TransactionData) == 1
                    NC = size(app.TransactionData,1) +1 ;
                else
                    app.TransactionData = {};
                    NC = 1;
                
                end
            else
                NC = size(app.TransactionData,1) +1;
            end


             app.TransactionData{NC,1} = app.Settle;
             app.TransactionData{NC,4} = app.Q;
             app.TransactionData{NC,3} = app.Pricer;
             app.TransactionData{NC,2} = app.Instr;
             app.TransactionData{NC,5} = app.InstSens.Currency;
             app.TransactionData{NC,6} = app.InstSens.OptionType;
             app.TransactionData{NC,7} = app.InstSens.CallPut;
             app.TransactionData{NC,8} = app.InstSens.Strike;
             app.TransactionData{NC,9} = app.InstSens.Maturity;
             app.TransactionData{NC,10} = app.InstSens;
            
             
             if size(app.TransactionData,1) == 1
                 
                [app.PortfolioData ,app.Revenues, app.CurrencyRev, app.DatesRev, app.Costs, app.CurrencyCost, app.DatesCost] = TransactionToPortfolioFirst(app.TransactionData);
              
             else if size(app.TransactionData) > 1
                     
                     % Usa funzione per analizzare le transazioni
                        [app.PortfolioData, app.Revenues, app.CurrencyRev, app.DatesRev, app.Costs, app.CurrencyCost, app.DatesCost] = portfolioFromTransaction(app.TransactionData,app.PortfolioData,app.Revenues, app.CurrencyRev, app.DatesRev, app.Costs, app.CurrencyCost, app.DatesCost);
                  end
             end

            
            app.UpdatePortfolioButton.Enable = 'off';
            app.UpdateDataButton.Enable = 'on';
            app.MinLimit = app.Settle;
            app.UpdatePortfolioButton.Enable = 'off';
            app.DeleteAllRegistrationsButtonPushed(app)
            
            
            app.RelevantInformationsTextArea.Value = "Transactions correctly added to Portfolio";
            
            %ERROR CHECK STEPLADDER
                        
[Instr,QQ, Pricer, Currency] = ReadLatestPortfolio(app.PortfolioData);

            
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(Instr)
Portfolio{1,i} = Instr(i);
Portfolio{2,i} = QQ(i);
Portfolio{3,i} = Pricer(i);
Portfolio{4,i} = string(char(Currency(i)));
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end

TESTQ = QQ(logical(TESTV)) == 0;

if nnz(TESTV) == 0 | nnz(TESTQ) == length(TESTQ)
    app.GenerateStepladderButton.Enable = 'off';
    app.StartBacktestingButton.Enable = 'off';
    app.TextArea_2.Value = ["No options available for the selected underlying. Select another underlying or store new options"];
    app.TextArea_2.FontColor = 'r';
else
    app.GenerateStepladderButton.Enable = 'on';
    app.StartBacktestingButton.Enable = 'on';
     app.TextArea_2.Value = ["Options for the selected underlying available. Stepladder analysis and VaR backtesting available."];
    app.TextArea_2.FontColor = [0 0.6 0];
end


        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Message","Profit")
            
            SelectedCurrency = app.UnderlyingDropDown_2.Items(app.UnderlyingDropDown_2.Value);


            %RICHIAMO FUNZIONE PER GENERARE LA SUMMARY TABLE
            SummaryTable = SummaryTableData(app.PortfolioData,SelectedCurrency);
            
            app.UITable11.Data = SummaryTable;
            app.UITable11.ColumnName = {'Portfolio Date' 'Net Long/Short Pos.' 'Long Number' 'Short Number' 'Call Number' 'Put Number' 'Mean Time to Exp.' 'Vanilla Options' 'Barrier Options' 'Binary Options' 'Lookback Options'};
            
            [Dates, PortPriceTS, CostRevTable] = ProfitLoss(app.PortfolioData,app.Revenues,app.CurrencyRev,app.DatesRev,app.Costs,app.CurrencyCost,app.DatesCost,SelectedCurrency);
            
            
            Profits = table(CostRevTable.Revenues-CostRevTable.Cost);
            app.UITable12.Data = [CostRevTable Profits table(PortPriceTS,'VariableNames',"VALUEts") table(dlog(PortPriceTS),'VariableNames',"Returns")];
            app.UITable12.ColumnName = {'Date', 'Cash Inflows', 'Cash Outflows', 'Net Flows', 'Daily Portfolio Value' 'Daily Performance'};
            
            
            plot(app.UIAxes11,Dates,table2array(Profits),"LineWidth",2)
            hold(app.UIAxes11,"on")
            plot(app.UIAxes11,Dates,CostRevTable.Cost,'.-')
            plot(app.UIAxes11,Dates,CostRevTable.Revenues,'--')
            hold(app.UIAxes11,"off")
            legend(app.UIAxes11,{'Net Flows' 'Cash Outflows' 'Cash Inflows'},"Location","best","Box","on","TextColor",'w',"EdgeColor",'w')
            
            plot(app.UIAxes12,Dates,PortPriceTS,'.-',"LineWidth",2)


            % VAR ON HISTORICAL PORTFOLIO


        end

        % Value changed function: UnderlyingDropDown_2
        function UnderlyingDropDown_2ValueChanged(app, event)
            value = app.UnderlyingDropDown_2.Value;
            SelectedCurrency = app.UnderlyingDropDown_2.Items(value);
            
            app.USDAUDPORTFOLIOLabel.Text = strjoin([convertCharsToStrings(SelectedCurrency) "PORTFOLIO"]);
        end

        % Value changed function: StepladderCurrencyDropDown
        function StepladderCurrencyDropDownValueChanged(app, event)
            value = app.StepladderCurrencyDropDown.Value;
            
            SelectedCurrency = app.StepladderCurrencyDropDown.Items(value);
            
            app.USDAUDPORTFOLIOLabel.Text = strjoin([convertCharsToStrings(SelectedCurrency) "PORTFOLIO SUMMARY"]);
            
            
[Instr,QQ, Pricer, Currency] = ReadLatestPortfolio(app.PortfolioData);

            
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(Instr)
Portfolio{1,i} = Instr(i);
Portfolio{2,i} = QQ(i);
Portfolio{3,i} = Pricer(i);
Portfolio{4,i} = string(char(Currency(i)));
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end

TESTQ = QQ(logical(TESTV)) == 0;

if nnz(TESTV) == 0 | nnz(TESTQ) == length(TESTQ)
    app.StartBacktestingButton.Enable = 'off';
    app.GenerateStepladderButton.Enable = 'off';
    app.TextArea_2.Value = ["No options available for the selected underlying. Select another underlying or store new options"];
    app.TextArea_2.FontColor = 'r';
else
    app.StartBacktestingButton.Enable = 'on';
    app.GenerateStepladderButton.Enable = 'on';
     app.TextArea_2.Value = ["Options for the selected underlying available. Stepladder analysis available."];
    app.TextArea_2.FontColor = [0 0.6 0];
end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            [app.Instr,app.Q,app.Pricer, app.counter,app.counter2,app.TestInstr,app.ShortInstrumentCounter, ...
                app.LongInstrumentCounter,app.OptionTypeCategoricalLong,app.OptionTypeCategoricalShort,app.LongCategories_Quantities,app.ShortCategories_Quantities, ...
                app.OptionTypeCategorical,app.Currency,app.InstSens,app.PortSens,app.InstPrice] = ReadPortfolioBackup(app.PortfolioBackup);
            
            if isempty(app.Instr) == 0
               UpdateHomePlots(app,app.InstPrice,app.InstSens,app.PortSens)
            else
                ReSetHomePlots(app)
            end
            
        end

        % Button pushed function: PlotPortfolioDataButton
        function PlotPortfolioDataButtonPushed(app, event)
            [Gamma, Delta, Prices, Maturities] =  GreekSensitivities(app.PriceMatrix, app.SensitivityChartCurrencyDropDown.Value , app.SensitivityChartCurrencyDropDown.Items(app.SensitivityChartCurrencyDropDown.Value), app.PortfolioData);
            
            mesh(app.UIAxes13,Maturities,Prices,Gamma,Delta)
            colorbar(app.UIAxes13,"horiz")
        end

        % Value changed function: VaRBacktestingHorizonDatePicker
        function VaRBacktestingHorizonDatePickerValueChanged(app, event)
            value = app.VaRBacktestingHorizonDatePicker.Value;
            app.StartDateDatePicker_4.Limits = [app.VaRBacktestingHorizonDatePicker.Value datetime("today")];
app.EndDateDatePicker_3.Limits = [app.StartDateDatePicker_4.Value+1 datetime("today")];
        end

        % Menu selected function: SavePortfolioasMenu
        function SavePortfolioasMenuSelected(app, event)
            if length(app.PortfolioData) >0 
                
            [file, path] = uiputfile('*.mat');
            filename = fullfile(path,file);
            
           Port = app.PortfolioData;
           Rev = app.Revenues;
            RevCurr = app.CurrencyRev;
            RevDates = app.DatesRev;
            Cost= app.Costs;
           CostCurr = app.CurrencyCost;
            DatesCost = app.DatesCost;
            save(filename, "Port","Rev","RevCurr","RevDates","Cost","CostCurr","DatesCost")
            
            else
                app.RelevantInformationsTextArea.Value = ["To save portfolio you have to add transaction to portfolio"];
                app.RelevantInformationsTextArea.FontColor = 'r';
            end
            
            
        end

        % Menu selected function: LoadPortfolioMenu
        function LoadPortfolioMenuSelected(app, event)
             [file, path] = uigetfile('*.mat');
            filename = fullfile(path,file);
            load(filename,"Port","Rev","RevCurr","RevDates","Cost","CostCurr","DatesCost")
            app.PortfolioData =  Port;
            app.Revenues = Rev;
            app.CurrencyRev = RevCurr;
            app.DatesRev = RevDates;
            app.Costs = Cost;
            app.CurrencyCost = CostCurr;
            app.DatesCost = DatesCost;
            
            
            if isequal(file,0)
                app.RelevantInformationsTextArea.Value = "User selected Cancel";
            else
                app.RelevantInformationsTextArea.Value = "Portfolio correctly loaded";
                app.RelevantInformationsTextArea.FontColor = [ 0 0.5 0];
            end
            
            app.UpdateDataButton.Enable = 'on';
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Risk Management Dashboard for Option Trading Desk v1.0 - The Black Swan Team';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavePortfolioasMenu = uimenu(app.FileMenu);
            app.SavePortfolioasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePortfolioasMenuSelected, true);
            app.SavePortfolioasMenu.Text = 'Save Portfolio as....';
            app.LoadPortfolioMenu = uimenu(app.FileMenu);
            app.LoadPortfolioMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPortfolioMenuSelected, true);
            app.LoadPortfolioMenu.Text = 'Load Portfolio';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortfolioSelectionTab = uitab(app.TabGroup);
            app.PortfolioSelectionTab.Title = 'Portfolio Selection';
            app.AddOptiontoPortfolioPanel = uipanel(app.PortfolioSelectionTab);
            app.AddOptiontoPortfolioPanel.Title = 'Add Option to Portfolio';
            app.SetDateandRatesButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.SetDateandRatesButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateandRatesButtonPushed, true);
            app.SetDateandRatesButton.Text = 'Set Date and Rates';
            app.AddRegistrationButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.AddRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegistrationButtonPushed, true);
            app.AddRegistrationButton.Enable = 'off';
            app.AddRegistrationButton.Text = 'Add Registration';
            app.DeleteAllRegistrationsButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.DeleteAllRegistrationsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllRegistrationsButtonPushed, true);
            app.DeleteAllRegistrationsButton.Text = 'Delete All Registrations';
            app.OptionStyleButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionStyleButtonGroupSelectionChanged, true);
            app.OptionStyleButtonGroup.Title = 'Option Style';
            app.EuropeanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.EuropeanButton.Text = 'European';
            app.EuropeanButton.Value = true;
            app.AmericanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.AmericanButton.Text = 'American';
            app.PositionButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.PositionButtonGroup.Title = 'Position';
            app.LongButton = uiradiobutton(app.PositionButtonGroup);
            app.LongButton.Text = 'Long';
            app.LongButton.Value = true;
            app.ShortButton = uiradiobutton(app.PositionButtonGroup);
            app.ShortButton.Text = {'Short'; ''};
            app.OptionTypeButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionTypeButtonGroup.Title = 'Option Type';
            app.CallButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.CallButton.Text = 'Call';
            app.CallButton.Value = true;
            app.PutButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.PutButton.Text = 'Put';
            app.InstrumentTypeDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDown.Items = {'Vanilla', 'Binary', 'Barrier', 'Lookback'};
            app.InstrumentTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentTypeDropDownValueChanged, true);
            app.InstrumentTypeDropDown.Value = 'Vanilla';
            app.InstrumentTypeDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDownLabel.Text = 'Instrument Type';
            app.StrikeEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.StrikeEditField.Limits = [0 Inf];
            app.StrikeEditField.Value = 90;
            app.StrikeEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.StrikeEditFieldLabel.Text = 'Strike';
            app.UnderlyingPriceEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingPriceEditFieldLabel.Text = 'Underlying Price';
            app.UnderlyingPriceEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.UnderlyingPriceEditField.Value = 90;
            app.VolatilityEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.VolatilityEditFieldLabel.Text = 'Volatility';
            app.VolatilityEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.VolatilityEditField.Value = 0.12;
            app.OptionMaturityDatePickerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePickerLabel.Text = 'Option Maturity';
            app.OptionMaturityDatePicker = uidatepicker(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePicker.Value = datetime([2021 3 3]);
            app.YieldEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.YieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.YieldLabel.Text = 'Yield (%)';
            app.BarrierPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BarrierPanel.Title = 'Barrier Panel';
            app.GridLayout = uigridlayout(app.BarrierPanel);
            app.BarrierTypeDropDown = uidropdown(app.GridLayout);
            app.BarrierTypeDropDown.Items = {'Up Knock-In', 'Up Knock-Out', 'Down Knock-In', 'Down Knock-Out'};
            app.BarrierTypeDropDown.ItemsData = {'UI', 'UO', 'DI', 'DO'};
            app.BarrierTypeDropDown.Value = 'UI';
            app.BarrierTypeLabel = uilabel(app.GridLayout);
            app.BarrierTypeLabel.Text = 'Barrier Type ';
            app.BarrierValueLabel = uilabel(app.GridLayout);
            app.BarrierValueLabel.Text = 'Barrier Value ';
            app.BarrierValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.BarrierValueEditField.Limits = [0 Inf];
            app.BinaryPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BinaryPanel.Title = 'Binary Panel';
            app.GridLayout2 = uigridlayout(app.BinaryPanel);
            app.BinaryValueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BinaryValueEditField.Limits = [0 Inf];
            app.BinaryValueEditFieldLabel = uilabel(app.GridLayout2);
            app.BinaryValueEditFieldLabel.Text = 'Binary Value';
            app.SimulationTrialsEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.SimulationTrialsEditFieldLabel.Text = 'Simulation Trials';
            app.SimulationTrialsEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.SimulationTrialsEditField.Enable = 'off';
            app.SimulationTrialsEditField.Value = 1000;
            app.QuantitySpinner = uispinner(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinner.Limits = [0 Inf];
            app.QuantitySpinner.Value = 1;
            app.QuantitySpinnerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinnerLabel.Text = 'Quantity';
            app.UnderlyingDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingDropDownLabel.Text = 'Underlying';
            app.UnderlyingDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.UnderlyingDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.UnderlyingDropDown.ValueChangedFcn = createCallbackFcn(app, @UnderlyingDropDownValueChanged, true);
            app.UnderlyingDropDown.Value = 'USD/AUD';
            app.UpdatePortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.UpdatePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortfolioButtonPushed, true);
            app.UpdatePortfolioButton.Enable = 'off';
            app.UpdatePortfolioButton.Text = 'Update Portfolio';
            app.RelevantInformationsTextAreaLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.RelevantInformationsTextAreaLabel.Text = 'Relevant Informations';
            app.RelevantInformationsTextArea = uitextarea(app.AddOptiontoPortfolioPanel);
            app.UndoButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.PortfolioCompositionPanel = uipanel(app.PortfolioSelectionTab);
            app.PortfolioCompositionPanel.Title = 'Portfolio Composition';
            app.UIAxes5 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable3 = uitable(app.PortfolioSelectionTab);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = [true true true true true true true true true true true true];
            app.UITable2 = uitable(app.PortfolioSelectionTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true true true true true true true true true true true];
            app.LONGTRANSACTIONSLabel = uilabel(app.PortfolioSelectionTab);
            app.LONGTRANSACTIONSLabel.Text = 'LONG TRANSACTIONS';
            app.SHORTTRANSACTIONSLabel = uilabel(app.PortfolioSelectionTab);
            app.SHORTTRANSACTIONSLabel.Text = 'SHORT TRANSACTIONS';
            app.DeskPerformanceTab = uitab(app.TabGroup);
            app.DeskPerformanceTab.Title = 'Desk Performance';
            app.PortfolioSummaryandCashFlowsPanel = uipanel(app.DeskPerformanceTab);
            app.PortfolioSummaryandCashFlowsPanel.Title = 'Portfolio Summary and Cash Flows ';
            app.UITable11 = uitable(app.PortfolioSummaryandCashFlowsPanel);
            app.UITable11.ColumnName = '';
            app.UITable11.RowName = {};
            app.UITable12 = uitable(app.PortfolioSummaryandCashFlowsPanel);
            app.UITable12.ColumnName = '';
            app.UITable12.RowName = {};
            app.USDAUDPORTFOLIOLabel = uilabel(app.PortfolioSummaryandCashFlowsPanel);
            app.USDAUDPORTFOLIOLabel.Text = 'USD/AUD PORTFOLIO';
            app.UnderlyingDropDown_2Label = uilabel(app.PortfolioSummaryandCashFlowsPanel);
            app.UnderlyingDropDown_2Label.Text = 'Underlying';
            app.UnderlyingDropDown_2 = uidropdown(app.PortfolioSummaryandCashFlowsPanel);
            app.UnderlyingDropDown_2.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.UnderlyingDropDown_2.ItemsData = [1 2 3 4 5 6];
            app.UnderlyingDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UnderlyingDropDown_2ValueChanged, true);
            app.UnderlyingDropDown_2.Value = 1;
            app.UpdateDataButton = uibutton(app.PortfolioSummaryandCashFlowsPanel, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Enable = 'off';
            app.UpdateDataButton.Text = 'Update Data';
            app.CashFlowsPanel = uipanel(app.DeskPerformanceTab);
            app.CashFlowsPanel.Title = 'Cash Flows';
            app.UIAxes11 = uiaxes(app.CashFlowsPanel);
            xlabel(app.UIAxes11, 'Date')
            ylabel(app.UIAxes11, 'Profits')
            zlabel(app.UIAxes11, 'Z')
            app.PortfolioPerformancePanel = uipanel(app.DeskPerformanceTab);
            app.PortfolioPerformancePanel.Title = 'Portfolio Performance';
            app.UIAxes12 = uiaxes(app.PortfolioPerformancePanel);
            xlabel(app.UIAxes12, 'Dates')
            ylabel(app.UIAxes12, ' Port. Performance')
            zlabel(app.UIAxes12, 'Z')
            app.TimeSeriesAnalysisTab = uitab(app.TabGroup);
            app.TimeSeriesAnalysisTab.Title = 'Time Series Analysis';
            app.TimeSeriesChartPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartPanel.Title = 'Time Series Chart';
            app.UIAxes6 = uiaxes(app.TimeSeriesChartPanel);
            xlabel(app.UIAxes6, 'Date')
            ylabel(app.UIAxes6, 'Exchange Rate')
            zlabel(app.UIAxes6, 'Z')
            app.TimeSeriesChartControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartControlPanel.Title = 'Time Series Chart Control Panel';
            app.StartDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Value = datetime([1995 3 2]);
            app.EndDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Value = datetime([2021 3 4]);
            app.Series1DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series1DropDownLabel.Text = 'Series 1';
            app.Series1DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series1DropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series1DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.Series1DropDown.ValueChangedFcn = createCallbackFcn(app, @Series1DropDownValueChanged, true);
            app.Series1DropDown.Value = '1';
            app.Series2DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series2DropDownLabel.Text = 'Series 2';
            app.Series2DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series2DropDown.Items = {'No plot', 'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series2DropDown.ValueChangedFcn = createCallbackFcn(app, @Series2DropDownValueChanged, true);
            app.Series2DropDown.Value = 'EUR/USD';
            app.LogReturnsDistributionFitterPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.LogReturnsDistributionFitterPanel.Title = 'Log-Returns Distribution Fitter';
            app.UIAxes7 = uiaxes(app.LogReturnsDistributionFitterPanel);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.CurrencyDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDownLabel.Text = 'Currency';
            app.CurrencyDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.CurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.CurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrencyDropDownValueChanged, true);
            app.CurrencyDropDown.Value = 1;
            app.StartDateDatePicker_3Label = uilabel(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3Label.Text = 'Start Date';
            app.StartDateDatePicker_3 = uidatepicker(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_3ValueChanged, true);
            app.OverlayDistributionCheckBox = uicheckbox(app.LogReturnsDistributionFitterPanel);
            app.OverlayDistributionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayDistributionCheckBoxValueChanged, true);
            app.OverlayDistributionCheckBox.Text = 'Overlay Distribution';
            app.DistributionTypeDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDown.Items = {'Normal', 'Student-t'};
            app.DistributionTypeDropDown.ItemsData = [1 2];
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Enable = 'off';
            app.DistributionTypeDropDown.Value = 1;
            app.TextArea = uitextarea(app.LogReturnsDistributionFitterPanel);
            app.UITable5 = uitable(app.TimeSeriesAnalysisTab);
            app.UITable5.ColumnName = '';
            app.UITable5.RowName = {};
            app.UITable5.ColumnSortable = [true true true true true true true true true true true true true true true true];
            app.CorrelationMapPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapPanel.Title = 'Correlation Map';
            app.CorrelationMapandSummaryStatisticsControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapandSummaryStatisticsControlPanel.Title = 'Correlation Map and Summary Statistics Control Panel';
            app.StartDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.StartDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_2ValueChanged, true);
            app.StartDateDatePicker_2.Value = datetime([1995 3 2]);
            app.EndDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.EndDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePicker_2ValueChanged, true);
            app.EndDateDatePicker_2.Value = datetime([2021 3 4]);
            app.BackgroundColorButton = uibutton(app.CorrelationMapandSummaryStatisticsControlPanel, 'state');
            app.BackgroundColorButton.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorButtonValueChanged, true);
            app.BackgroundColorButton.Text = 'Background Color';
            app.BackgroundColorButton.Value = true;
            app.VaRCalculationandBacktestingTab = uitab(app.TabGroup);
            app.VaRCalculationandBacktestingTab.Title = 'VaR Calculation and Backtesting';
            app.UIAxes8 = uiaxes(app.VaRCalculationandBacktestingTab);
            xlabel(app.UIAxes8, 'Date')
            ylabel(app.UIAxes8, 'VaR')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.VaRCalculationandBacktestingTab);
            xlabel(app.UIAxes9, 'Date')
            ylabel(app.UIAxes9, 'Returns')
            zlabel(app.UIAxes9, 'Z')
            app.UITable6 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable6.ColumnName = '';
            app.UITable6.RowName = {};
            app.UITable7 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable7.ColumnName = '';
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable8.ColumnName = '';
            app.UITable8.RowName = {};
            app.UITable10 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable10.ColumnName = '';
            app.UITable10.RowName = {};
            app.VARBACKTESTINGLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VARBACKTESTINGLabel.Text = 'VAR BACKTESTING';
            app.CURRENTPORTFOLIOVARANDESLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.CURRENTPORTFOLIOVARANDESLabel.Text = 'CURRENT PORTFOLIO VAR AND ES';
            app.STEPLADDERANALYSISLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.STEPLADDERANALYSISLabel.Text = 'STEPLADDER ANALYSIS';
            app.USDAUDPORTFOLIOSUMMARYLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.USDAUDPORTFOLIOSUMMARYLabel.Text = 'USD/AUD PORTFOLIO SUMMARY';
            app.MainControlPanel = uipanel(app.VaRCalculationandBacktestingTab);
            app.MainControlPanel.Title = 'Main Control Panel';
            app.StepladderCurrencyDropDownLabel = uilabel(app.MainControlPanel);
            app.StepladderCurrencyDropDownLabel.Text = 'Stepladder Currency';
            app.StepladderCurrencyDropDown = uidropdown(app.MainControlPanel);
            app.StepladderCurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.StepladderCurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.StepladderCurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @StepladderCurrencyDropDownValueChanged, true);
            app.StepladderCurrencyDropDown.Value = 1;
            app.SamplingPeriodDropDownLabel = uilabel(app.MainControlPanel);
            app.SamplingPeriodDropDownLabel.Text = 'Sampling Period';
            app.SamplingPeriodDropDown = uidropdown(app.MainControlPanel);
            app.SamplingPeriodDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingPeriodDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingPeriodDropDown.Value = 6;
            app.RiskHorizonEditFieldLabel = uilabel(app.MainControlPanel);
            app.RiskHorizonEditFieldLabel.Text = 'Risk Horizon';
            app.RiskHorizonEditField = uieditfield(app.MainControlPanel, 'numeric');
            app.RiskHorizonEditField.Value = 1;
            app.TextArea_2 = uitextarea(app.MainControlPanel);
            app.GenerateStepladderButton = uibutton(app.MainControlPanel, 'push');
            app.GenerateStepladderButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStepladderButtonPushed, true);
            app.GenerateStepladderButton.Text = 'Generate Stepladder';
            app.DeskVaRLimitEditFieldLabel = uilabel(app.MainControlPanel);
            app.DeskVaRLimitEditFieldLabel.Text = 'Desk VaR Limit ';
            app.DeskVaRLimitEditField = uieditfield(app.MainControlPanel, 'numeric');
            app.DeskVaRLimitEditField.Limits = [0.001 Inf];
            app.DeskVaRLimitEditField.Value = 0.001;
            app.VaRBacktestingControlsPanel = uipanel(app.VaRCalculationandBacktestingTab);
            app.VaRBacktestingControlsPanel.Title = 'VaR Backtesting Controls';
            app.VaRBacktestingHorizonDatePickerLabel = uilabel(app.VaRBacktestingControlsPanel);
            app.VaRBacktestingHorizonDatePickerLabel.Text = 'VaR Backtesting Horizon';
            app.VaRBacktestingHorizonDatePicker = uidatepicker(app.VaRBacktestingControlsPanel);
            app.VaRBacktestingHorizonDatePicker.ValueChangedFcn = createCallbackFcn(app, @VaRBacktestingHorizonDatePickerValueChanged, true);
            app.VaRBacktestingHorizonDatePicker.Value = datetime([2020 3 11]);
            app.SamplingRollingWindowDropDownLabel = uilabel(app.VaRBacktestingControlsPanel);
            app.SamplingRollingWindowDropDownLabel.Text = 'Sampling Rolling Window';
            app.SamplingRollingWindowDropDown = uidropdown(app.VaRBacktestingControlsPanel);
            app.SamplingRollingWindowDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingRollingWindowDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingRollingWindowDropDown.Value = 6;
            app.StartBacktestingButton = uibutton(app.VaRBacktestingControlsPanel, 'push');
            app.StartBacktestingButton.ButtonPushedFcn = createCallbackFcn(app, @StartBacktestingButtonPushed, true);
            app.StartBacktestingButton.Text = 'Start Backtesting';
            app.Label = uilabel(app.VaRBacktestingControlsPanel);
            app.Label.Text = 'Warning: VaR backtesting may take several minutes, depending by the choosen horizon and complexity of the portfolio';
            app.VaRLimitsCheckControlPanel_2 = uipanel(app.VaRCalculationandBacktestingTab);
            app.VaRLimitsCheckControlPanel_2.Title = 'VaR Limits Check Control';
            app.StartDateDatePicker_4Label = uilabel(app.VaRLimitsCheckControlPanel_2);
            app.StartDateDatePicker_4Label.Text = 'Start Date';
            app.StartDateDatePicker_4 = uidatepicker(app.VaRLimitsCheckControlPanel_2);
            app.StartDateDatePicker_4.Value = datetime([2021 3 16]);
            app.EndDateDatePicker_3Label = uilabel(app.VaRLimitsCheckControlPanel_2);
            app.EndDateDatePicker_3Label.Text = 'End Date';
            app.EndDateDatePicker_3 = uidatepicker(app.VaRLimitsCheckControlPanel_2);
            app.EndDateDatePicker_3.Value = datetime([2021 3 17]);
            app.VolatilitySurfaceandPortfolioGreksChartsTab = uitab(app.TabGroup);
            app.VolatilitySurfaceandPortfolioGreksChartsTab.Title = 'Volatility Surface and Portfolio Greks Charts';
            app.PortfolioGammaandDeltaPanel = uipanel(app.VolatilitySurfaceandPortfolioGreksChartsTab);
            app.PortfolioGammaandDeltaPanel.Title = 'Portfolio Gamma and Delta';
            app.UIAxes13 = uiaxes(app.PortfolioGammaandDeltaPanel);
            xlabel(app.UIAxes13, 'Time to Maturity')
            ylabel(app.UIAxes13, 'Underlying')
            zlabel(app.UIAxes13, 'Gamma')
            app.SensitivityChartCurrencyDropDownLabel = uilabel(app.PortfolioGammaandDeltaPanel);
            app.SensitivityChartCurrencyDropDownLabel.Text = 'Sensitivity Chart Currency';
            app.SensitivityChartCurrencyDropDown = uidropdown(app.PortfolioGammaandDeltaPanel);
            app.SensitivityChartCurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.SensitivityChartCurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.SensitivityChartCurrencyDropDown.Value = 1;
            app.PlotPortfolioDataButton = uibutton(app.PortfolioGammaandDeltaPanel, 'push');
            app.PlotPortfolioDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPortfolioDataButtonPushed, true);
            app.PlotPortfolioDataButton.Text = 'Plot Portfolio Data';
            app.VolatilitySurfacePlotPanel = uipanel(app.VolatilitySurfaceandPortfolioGreksChartsTab);
            app.VolatilitySurfacePlotPanel.Title = 'Volatility Surface Plot';
            app.UIAxes10 = uiaxes(app.VolatilitySurfacePlotPanel);
            zlabel(app.UIAxes10, 'Z')
            app.ImportOptionsDataButton = uibutton(app.VolatilitySurfacePlotPanel, 'push');
            app.ImportOptionsDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportOptionsDataButtonPushed, true);
            app.ImportOptionsDataButton.Text = 'Import Options Data';
            app.GenerateChartButton = uibutton(app.VolatilitySurfacePlotPanel, 'push');
            app.GenerateChartButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateChartButtonPushed, true);
            app.GenerateChartButton.Text = 'Generate Chart';
            app.UnderlyingEditFieldLabel = uilabel(app.VolatilitySurfacePlotPanel);
            app.UnderlyingEditFieldLabel.Text = 'Underlying';
            app.UnderlyingEditField = uieditfield(app.VolatilitySurfacePlotPanel, 'numeric');
            app.RateEditFieldLabel = uilabel(app.VolatilitySurfacePlotPanel);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.VolatilitySurfacePlotPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end