classdef RateandSettleDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RateandDateInterfaceUIFigure  matlab.ui.Figure
        RateChartOptionsPanel         matlab.ui.container.Panel
        InterpolationDropDown         matlab.ui.control.DropDown
        InterpolationDropDownLabel    matlab.ui.control.Label
        CurrencyDropDown              matlab.ui.control.DropDown
        CurrencyDropDownLabel         matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        AddRatetoCurveButton          matlab.ui.control.Button
        RateEditField                 matlab.ui.control.NumericEditField
        RateEditFieldLabel            matlab.ui.control.Label
        RatedateDatePicker            matlab.ui.control.DatePicker
        RatedateDatePickerLabel       matlab.ui.control.Label
        CurrencyButtonGroup           matlab.ui.container.ButtonGroup
        GBPButton                     matlab.ui.control.ToggleButton
        AUDButton_2                   matlab.ui.control.ToggleButton
        USDButton_2                   matlab.ui.control.ToggleButton
        EURButton_2                   matlab.ui.control.ToggleButton
        SettleDatePicker              matlab.ui.control.DatePicker
        SettleDatePickerLabel         matlab.ui.control.Label
        DeleteAllButton               matlab.ui.control.Button
        StoreDataButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        WarningDialog % if rates have been already selected
        CallingApp % PRMIA
             
    end
    
    properties (Access = private)
        tempTABLE
        Settle
        DatesEUR
        RatesEUR
        DatesUSD
        RatesUSD
        DatesAUD
        RatesAUD
        DatesGBP
        RatesGBP
        RateCurve
        counterEUR = 0
        counter2EUR = 0
        counterUSD = 0
        counter2USD = 0
        counterAUD = 0
        counter2AUD = 0
        counterGBP = 0
        counter2GBP = 0
        TestEUR
        TestUSD
        TestAUD
        TestGBP
    end
    
    methods (Access = private)
        
        function  ZeroCurve = ZeroCurveCalc(app,Settle,Rates,Dates)
            ZeroCurve = ratecurve("zero",Settle,Dates,Rates);
            
        end
        
        function UpdatePlot(app)


            if app.CurrencyDropDown.Value == 'EUR' & isempty(app.DatesEUR) == 0
                
                 if isrow(app.RatesEUR) == 1            
                    EURRATES = app.RatesEUR';
                else
                    EURRATES = app.RatesEUR;
                end
                if isrow(app.DatesEUR) == 1
                    EURDATE = app.DatesEUR';
                else
                    EURDATE = app.DatesEUR;
                end
            
            
            nxEUR = app.Settle:days(2):EURDATE(end);
            IntPointsEUR = interp1([app.Settle; EURDATE],[0 ;EURRATES],nxEUR,app.InterpolationDropDown.Value);
            plot(app.UIAxes,[app.Settle; EURDATE],[0; EURRATES],nxEUR,IntPointsEUR)
            ylim(app.UIAxes,[0 max(EURRATES)+0.02])
            else if app.CurrencyDropDown.Value == 'EUR' & isempty(app.DatesEUR) == 1
                app.TextArea.Value = "Curve plot not available. There are no EUR rates data";
                app.TextArea.FontColor = [255/255 165/255 0];
                end
            end


            if app.CurrencyDropDown.Value == 'USD' & isempty(app.DatesUSD) == 0
                
                  if isrow(app.RatesUSD) == 1            
            USDRATES = app.RatesUSD';
            else
                USDRATES = app.RatesUSD;
            end
            if isrow(app.DatesUSD) == 1
                USDDATE = app.DatesUSD';
            else
                USDDATE = app.DatesUSD;
            end
            
             nxUSD = app.Settle:days(2):USDDATE(end);
            IntPointsUSD = interp1([app.Settle; USDDATE],[0 ;USDRATES],nxUSD,app.InterpolationDropDown.Value);
            plot(app.UIAxes,[app.Settle; USDDATE],[0 ;USDRATES],nxUSD,IntPointsUSD)
            ylim(app.UIAxes,[0 max(USDRATES)+0.02])
            else if app.CurrencyDropDown.Value == 'USD' & isempty(app.DatesUSD) == 1
                app.TextArea.Value = "Curve plot not available. There are no USD rates data";
                app.TextArea.FontColor = [255/255 165/255 0];
                end
            end
            
            if app.CurrencyDropDown.Value == 'AUD' & isempty(app.DatesAUD) == 0
                
                 if isrow(app.RatesAUD) == 1            
            AUDRATES = app.RatesAUD';
            else
                AUDRATES = app.RatesAUD;
            end
            if isrow(app.DatesAUD) == 1
                AUDDATE = app.DatesAUD';
            else
                AUDDATE = app.DatesAUD;
            end
            
             nxAUD = app.Settle:days(2):AUDDATE(end);
            IntPointsAUD = interp1([app.Settle; AUDDATE],[0 ;AUDRATES],nxAUD,app.InterpolationDropDown.Value);
            plot(app.UIAxes,[app.Settle ;AUDDATE],[0; AUDRATES],nxAUD,IntPointsAUD)
            ylim(app.UIAxes,[0 max(AUDRATES)+0.02])
            else if app.CurrencyDropDown.Value == 'AUD' & isempty(app.DatesAUD) == 1
                app.TextArea.Value = "Curve plot not available. There are no AUD rates data";
                app.TextArea.FontColor = [255/255 165/255 0];
            end
            end
            
            if app.CurrencyDropDown.Value == 'GBP' & isempty(app.DatesGBP) == 0
                
                 if isrow(app.RatesGBP) == 1            
            GBPRATES = app.RatesGBP';
            else
                GBPRATES = app.RatesGBP;
            end
            if isrow(app.DatesGBP) == 1
                GBPDATE = app.DatesGBP';
            else
                GBPDATE = app.DatesGBP;
            end
            
             nxGBP = app.Settle:days(2):GBPDATE(end);
            IntPointsGBP = interp1([app.Settle; GBPDATE],[0 ;GBPRATES],nxGBP,app.InterpolationDropDown.Value);
            plot(app.UIAxes,[app.Settle; GBPDATE],[0; GBPRATES],nxGBP,IntPointsGBP)
            ylim(app.UIAxes,[0 max(GBPRATES)+0.02])
            else if app.CurrencyDropDown.Value == 'GBP' & isempty(app.DatesGBP) == 1
                app.TextArea.Value = "Curve plot not available. There are no GBP rates data";
                app.TextArea.FontColor = [255/255 165/255 0];
            end
            end


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.SettleDatePicker.Value = datetime("today");
            app.RatedateDatePicker.Value = app.SettleDatePicker.Value +31;
            app.RatedateDatePicker.Limits = [app.SettleDatePicker.Value app.SettleDatePicker.Value+calyears(60)]
            
            
            MinDate = app.CallingApp.MinLimit;
            if isempty(MinDate) == 0
            app.SettleDatePicker.Limits = [MinDate datetime("today")];
            end
        end

        % Button pushed function: AddRatetoCurveButton
        function AddRatetoCurveButtonPushed(app, event)
            app.Settle = app.SettleDatePicker.Value;


% EURO SELECTED           
            if app.EURButton_2.Value == true
                
                %Conversione dati in riga
                if isrow(app.RatesEUR) == 0            
                    app.RatesEUR =app.RatesEUR';
                end
                if isrow(app.DatesEUR) == 0
                    app.RatesEUR = app.DatesEUR';
                end
                
                app.counterEUR = app.counterEUR + 1;
                app.RatesEUR = [app.RatesEUR app.RateEditField.Value];
                app.DatesEUR = [app.DatesEUR app.RatedateDatePicker.Value];


                app.TestEUR(1) = 0;
                if app.counterEUR == 1
                else 
                    for i = 1:app.counterEUR-app.counter2EUR-1
                        if app.DatesEUR(i) == app.DatesEUR(app.counterEUR-app.counter2EUR)
                            app.TestEUR(i) = 1;
                        else
                            app.TestEUR(i) = 0;
                        end
                    end
                end
            
                if nnz(app.TestEUR)>0
                    app.AddRatetoCurveButton.Enable = 'off';
                    app.WarningDialog = RateWarningDialog(app);
                    app.TextArea.Value = "Curve nob selected already exists"
                    app.DatesEUR(app.counterEUR-app.counter2EUR) = [];
                    app.RatesEUR(app.counterEUR-app.counter2EUR) = [];
                    app.counter2EUR = app.counter2EUR + 1; 
                end
                
            end
            
% USD SELECTED           
            if app.USDButton_2.Value == true
                
                if isrow(app.RatesUSD) == 0            
                    app.RatesUSD =app.RatesUSD';
                end
                if isrow(app.DatesUSD) == 0
                    app.RatesUSD = app.DatesUSD';
                end
                
                app.counterUSD = app.counterUSD + 1;
                app.RatesUSD = [app.RatesUSD app.RateEditField.Value];
                app.DatesUSD = [app.DatesUSD app.RatedateDatePicker.Value];


                app.TestUSD(1) = 0;
                if app.counterUSD == 1
                else 
                    for i = 1:app.counterUSD-app.counter2USD-1
                        if app.DatesUSD(i) == app.DatesUSD(app.counterUSD-app.counter2USD)
                            app.TestUSD(i) = 1;
                        else
                            app.TestUSD(i) = 0;
                        end
                    end
                end
            
                if nnz(app.TestUSD)>0
                    app.AddRatetoCurveButton.Enable = 'off';
                    app.WarningDialog = RateWarningDialog(app);
                    app.TextArea.Value = "Curve nob selected already exists"
                    app.DatesUSD(app.counterUSD-app.counter2USD) = [];
                    app.RatesUSD(app.counterUSD-app.counter2USD) = [];
                    app.counter2USD = app.counterUSD + 1; 
                end
                
            end   
            
% AUD SELECTED           
            if app.AUDButton_2.Value == true
                
                if isrow(app.RatesAUD) == 0            
                    app.RatesAUD =app.RatesAUD';
                end
                if isrow(app.DatesAUD) == 0
                    app.RatesAUD = app.DatesAUD';
                end
                
                app.counterAUD = app.counterAUD + 1;
                app.RatesAUD = [app.RatesAUD app.RateEditField.Value];
                app.DatesAUD = [app.DatesAUD app.RatedateDatePicker.Value];


                app.TestAUD(1) = 0;
                if app.counterAUD == 1
                else 
                    for i = 1:app.counterAUD-app.counter2AUD-1
                        if app.DatesAUD(i) == app.DatesAUD(app.counterAUD-app.counter2AUD)
                            app.TestAUD(i) = 1;
                        else
                            app.TestAUD(i) = 0;
                        end
                    end
                end
            
                if nnz(app.TestAUD)>0
                    app.AddRatetoCurveButton.Enable = 'off';
                    app.WarningDialog = RateWarningDialog(app);
                    app.TextArea.Value = "Curve nob selected already exists"
                    app.DatesAUD(app.counterAUD-app.counter2AUD) = [];
                    app.RatesAUD(app.counterAUD-app.counter2AUD) = [];
                    app.counter2AUD = app.counterAUD + 1; 
                end
                
            end                        
            
            
% GBP SELECTED           
            if app.GBPButton.Value == true
                
                if isrow(app.RatesGBP) == 0            
                    app.RatesGBP =app.RatesGBP';
                end
                if isrow(app.DatesGBP) == 0
                    app.RatesGBP = app.DatesGBP';
                end
                
                app.counterGBP = app.counterGBP + 1;
                app.RatesGBP = [app.RatesGBP app.RateEditField.Value];
                app.DatesGBP = [app.DatesGBP app.RatedateDatePicker.Value];


                app.TestGBP(1) = 0;
                if app.counterGBP == 1
                else 
                    for i = 1:app.counterGBP-app.counter2GBP-1
                        if app.DatesGBP(i) == app.DatesGBP(app.counterGBP-app.counter2GBP)
                            app.TestGBP(i) = 1;
                        else
                            app.TestGBP(i) = 0;
                        end
                    end
                end
            
                if nnz(app.TestGBP)>0
                    app.AddRatetoCurveButton.Enable = 'off';
                    app.WarningDialog = RateWarningDialog(app);
                    app.TextArea.Value = "Curve nob selected already exists"
                    app.DatesGBP(app.counterGBP-app.counter2GBP) = [];
                    app.RatesGBP(app.counterGBP-app.counter2GBP) = [];
                    app.counter2GBP = app.counterGBP + 1; 
                end
                
            end                        
            
            GenDates = unique([app.DatesEUR app.DatesUSD app.DatesAUD app.DatesGBP]);
            
            if isempty(app.DatesEUR) == 0
                [~, GenDatesIndexEUR] = intersect(GenDates,app.DatesEUR);
            end
            
            if isempty(app.DatesUSD) == 0
                [~, GenDatesIndexUSD] = intersect(GenDates,app.DatesUSD);
            end
            
            if isempty(app.DatesAUD) == 0
                [~, GenDatesIndexAUD] = intersect(GenDates,app.DatesAUD);
            end
            
             if isempty(app.DatesGBP) == 0
                [~, GenDatesIndexGBP] = intersect(GenDates,app.DatesGBP);
            end
            
            
            GenRates = table(0);
            GenRates.Date = 0;
            
            %Aggiungere if per spostare le colonne
            if isempty(app.RatesEUR) == 0
                GenRates.EUR(GenDatesIndexEUR) = app.RatesEUR;
            end
            
            if isempty(app.RatesUSD) == 0
                GenRates.USD(GenDatesIndexUSD) = app.RatesUSD;
            end
            
            if isempty(app.RatesAUD) == 0
                GenRates.AUD(GenDatesIndexAUD) = app.RatesAUD;
            end


             if isempty(app.RatesGBP) == 0
                GenRates.GBP(GenDatesIndexGBP) = app.RatesGBP;
            end
            
            GenRates.Var1 = [];


            GenRates.Date = GenDates';
            MatrixTemp = table2array(GenRates(:,2:end));
            index = MatrixTemp == 0;
            [row,col] = find(index);
            
            if nnz(index) >0
            for i = 1:height(row)
                GenRates{row(i),col(i)+1} = nan;
            end
            end
            
            app.tempTABLE = GenRates;    


            app.UITable.ColumnName = app.tempTABLE.Properties.VariableNames;
            
            
            app.UITable.Data = app.tempTABLE;
            UpdatePlot(app)
            
        end

        % Button pushed function: StoreDataButton
        function StoreDataButtonPushed(app, event)
            app.CallingApp.Settle = app.Settle; 
            if isempty(app.RatesEUR) == 0
            app.CallingApp.ZeroCurveEUR = ZeroCurveCalc(app,app.SettleDatePicker.Value,app.RatesEUR,app.DatesEUR); 
            end
            
            if isempty(app.RatesUSD) == 0
            app.CallingApp.ZeroCurveUSD = ZeroCurveCalc(app,app.SettleDatePicker.Value,app.RatesUSD,app.DatesUSD); 
            end
            
            if isempty(app.RatesAUD) == 0
            app.CallingApp.ZeroCurveAUD = ZeroCurveCalc(app,app.SettleDatePicker.Value,app.RatesAUD,app.DatesAUD); 
            end
            
            if isempty(app.RatesGBP) == 0
            app.CallingApp.ZeroCurveGBP = ZeroCurveCalc(app,app.SettleDatePicker.Value,app.RatesGBP,app.DatesGBP); 
            end


            app.CallingApp.TestRatesError(app.CallingApp.UnderlyingDropDown.Value(1:3))
            app.CallingApp.OptionMaturityDatePicker.Limits = [app.Settle+2 app.Settle+calyears(60) ];
            app.CallingApp.OptionMaturityDatePicker.Value = app.Settle +31;
            app.CallingApp.SetDateandRatesButton.Enable = 'on';
            app.CallingApp.AddRegistrationButton.Enable = 'on';
            delete(app)
        end

        % Close request function: RateandDateInterfaceUIFigure
        function RateandDateInterfaceUIFigureCloseRequest(app, event)
            delete(app.WarningDialog)
            delete(app)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            indices = event.Indices;
            newData = event.NewData;
            
           app.tempTABLE{indices(1),indices(2)} = newData;
           
           Exist_Column_EUR = strcmp('EUR',app.tempTABLE.Properties.VariableNames);
            Exist_Column_USD = strcmp('USD',app.tempTABLE.Properties.VariableNames);
             Exist_Column_AUD = strcmp('AUD',app.tempTABLE.Properties.VariableNames);
              Exist_Column_GBP = strcmp('GBP',app.tempTABLE.Properties.VariableNames);

            
           if nnz(Exist_Column_EUR) == 1
               app.RatesEUR = rmmissing(app.tempTABLE.EUR); % Trasposti perchè presi dalla tabella sono vettori colonna, ma servono dopo come vettori riga
           end
           
           if strcmp(app.tempTABLE.Properties.VariableNames(indices(2)),'EUR') == 1 & nnz(app.DatesEUR == app.tempTABLE.Date(indices(1))) == 0 
               app.DatesEUR(end+1) = app.tempTABLE.Date(indices(1)) ;
                app.DatesEUR = sort(app.DatesEUR);
           end


            if nnz(Exist_Column_USD) == 0
                
            else
                
                app.RatesUSD = rmmissing(app.tempTABLE.USD); % Trasposti perchè presi dalla tabella sono vettori colonna, ma servono dopo come vettori riga
            end
            if strcmp(app.tempTABLE.Properties.VariableNames(indices(2)),'USD') == 1 & nnz(app.DatesUSD == app.tempTABLE.Date(indices(1))) == 0 
               app.DatesUSD(end+1) = app.tempTABLE.Date(indices(1)) ;
                app.DatesUSD = sort(app.DatesUSD);
           end


             if nnz(Exist_Column_AUD) == 0
                
             else
                 
                app.RatesAUD = rmmissing(app.tempTABLE.AUD); % Trasposti perchè presi dalla tabella sono vettori colonna, ma servono dopo come vettori riga
             end
             if strcmp(app.tempTABLE.Properties.VariableNames(indices(2)),'AUD') == 1 & nnz(app.DatesAUD == app.tempTABLE.Date(indices(1))) == 0 
               app.DatesAUD(end+1) = app.tempTABLE.Date(indices(1)) ;
                app.DatesAUD = sort(app.DatesAUD);
           end


              if nnz(Exist_Column_GBP) == 0
                
              else
                  
                app.RatesGBP = rmmissing(app.tempTABLE.GBP); % Trasposti perchè presi dalla tabella sono vettori colonna, ma servono dopo come vettori riga
              end
              if strcmp(app.tempTABLE.Properties.VariableNames(indices(2)),'GBP') == 1 & nnz(app.DatesGBP == app.tempTABLE.Date(indices(1))) == 0 
               app.DatesGBP(end+1) = app.tempTABLE.Date(indices(1)) ;
                app.DatesGBP = sort(app.DatesGBP);
           end
            
            UpdatePlot(app)
            
        end

        % Value changed function: CurrencyDropDown
        function CurrencyDropDownValueChanged(app, event)
            value = app.CurrencyDropDown.Value;
            
            UpdatePlot(app)
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            value = app.InterpolationDropDown.Value;
            
            UpdatePlot(app)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            app.AddRatetoCurveButton.Enable = 'off';
            app.StoreDataButton.Enable = 'off';
            app.DeleteAllButton.Enable = 'off';
            app.InterpolationDropDown.Enable = 'off';
            app.CurrencyDropDown.Enable = 'off';
            
            app.TextArea.Value = "Press a key to re-instate buttons";
        end

        % Key press function: RateandDateInterfaceUIFigure
        function RateandDateInterfaceUIFigureKeyPress(app, event)
            key = event.Key;
            app.AddRatetoCurveButton.Enable = 'on';
            app.StoreDataButton.Enable = 'on';
            app.DeleteAllButton.Enable = 'on';
            app.InterpolationDropDown.Enable = 'on';
            app.CurrencyDropDown.Enable = 'on';
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
        app.DatesEUR = [];
        app.RatesEUR = [];
        app.DatesUSD = [];
        app.RatesUSD = [];
        app.DatesAUD = [];
        app.RatesAUD = [];
        app.DatesGBP = [];
        app.RatesGBP = [];
        app.RateCurve = [];
        app.counterEUR = 0;
        app.counter2EUR = 0;
        app.counterUSD = 0;
        app.counter2USD = 0;
        app.counterAUD = 0;
        app.counter2AUD = 0;
        app.counterGBP = 0;
        app.counter2GBP = 0;
        app.TestEUR = [];
        app.TestUSD = [];
        app.TestAUD = [];
        app.TestGBP = [];
        
% aggirna grafici        
        app.UITable.Data = [];
        plot(app.UIAxes,0,0)
        end

        % Value changed function: RatedateDatePicker
        function RatedateDatePickerValueChanged(app, event)
            value = app.RatedateDatePicker.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RateandDateInterfaceUIFigure = uifigure('Visible', 'off');
            app.RateandDateInterfaceUIFigure.Name = 'Rate and Date Interface';
            app.RateandDateInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @RateandDateInterfaceUIFigureCloseRequest, true);
            app.RateandDateInterfaceUIFigure.KeyPressFcn = createCallbackFcn(app, @RateandDateInterfaceUIFigureKeyPress, true);
            app.StoreDataButton = uibutton(app.RateandDateInterfaceUIFigure, 'push');
            app.StoreDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreDataButtonPushed, true);
            app.StoreDataButton.Text = 'Store Data';
            app.DeleteAllButton = uibutton(app.RateandDateInterfaceUIFigure, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.Panel = uipanel(app.RateandDateInterfaceUIFigure);
            app.Panel.Title = 'Panel';
            app.SettleDatePickerLabel = uilabel(app.Panel);
            app.SettleDatePickerLabel.Text = 'Settle';
            app.SettleDatePicker = uidatepicker(app.Panel);
            app.SettleDatePicker.Value = datetime([2021 3 2]);
            app.CurrencyButtonGroup = uibuttongroup(app.Panel);
            app.CurrencyButtonGroup.Title = 'Currency';
            app.EURButton_2 = uitogglebutton(app.CurrencyButtonGroup);
            app.EURButton_2.Text = 'EUR';
            app.EURButton_2.Value = true;
            app.USDButton_2 = uitogglebutton(app.CurrencyButtonGroup);
            app.USDButton_2.Text = 'USD';
            app.AUDButton_2 = uitogglebutton(app.CurrencyButtonGroup);
            app.AUDButton_2.Text = 'AUD';
            app.GBPButton = uitogglebutton(app.CurrencyButtonGroup);
            app.GBPButton.Text = 'GBP';
            app.RatedateDatePickerLabel = uilabel(app.Panel);
            app.RatedateDatePickerLabel.Text = 'Rate date';
            app.RatedateDatePicker = uidatepicker(app.Panel);
            app.RatedateDatePicker.ValueChangedFcn = createCallbackFcn(app, @RatedateDatePickerValueChanged, true);
            app.RatedateDatePicker.Value = datetime([2021 4 2]);
            app.RateEditFieldLabel = uilabel(app.Panel);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.Panel, 'numeric');
            app.RateEditField.Value = 0.05;
            app.AddRatetoCurveButton = uibutton(app.Panel, 'push');
            app.AddRatetoCurveButton.ButtonPushedFcn = createCallbackFcn(app, @AddRatetoCurveButtonPushed, true);
            app.AddRatetoCurveButton.Text = 'Add Rate to Curve';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.Panel);
            app.RateChartOptionsPanel = uipanel(app.RateandDateInterfaceUIFigure);
            app.RateChartOptionsPanel.Title = 'Rate Chart Options';
            app.UIAxes = uiaxes(app.RateChartOptionsPanel);
            title(app.UIAxes, 'Zero Curve')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Rate')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RateChartOptionsPanel);
            app.UITable.ColumnName = {'Date'; 'Rate'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CurrencyDropDownLabel = uilabel(app.RateChartOptionsPanel);
            app.CurrencyDropDownLabel.Text = 'Currency';
            app.CurrencyDropDown = uidropdown(app.RateChartOptionsPanel);
            app.CurrencyDropDown.Items = {'EUR', 'USD', 'AUD', 'GBP'};
            app.CurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrencyDropDownValueChanged, true);
            app.CurrencyDropDown.Value = 'EUR';
            app.InterpolationDropDownLabel = uilabel(app.RateChartOptionsPanel);
            app.InterpolationDropDownLabel.Text = {'Interpolation'; ''};
            app.InterpolationDropDown = uidropdown(app.RateChartOptionsPanel);
            app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Spline'};
            app.InterpolationDropDown.ItemsData = {'linear', 'cubic', 'spline', ''};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Value = 'linear';
            % Show the figure after all components are created
            app.RateandDateInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RateandSettleDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RateandDateInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RateandDateInterfaceUIFigure)
        end
    end
end